classdef GVA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GVAUIFigure                     matlab.ui.Figure
        SelectExperimentalDirectoryButton  matlab.ui.control.Button
        ExpDirEditField                 matlab.ui.control.EditField
        PipettesRowEditField            matlab.ui.control.NumericEditField
        AssayVolumeuLEditField          matlab.ui.control.NumericEditField
        ChannelLengthmmEditField        matlab.ui.control.NumericEditField
        ImagesFOVEditField              matlab.ui.control.NumericEditField
        PipettesFOVEditField            matlab.ui.control.NumericEditField
        RotateImageCheckBox             matlab.ui.control.CheckBox
        PixelsizemmEditField            matlab.ui.control.NumericEditField
        StoreasDefaultConfigurationButton  matlab.ui.control.Button
        DilutionFactorEditField         matlab.ui.control.NumericEditField
        ExperimentDateEditField         matlab.ui.control.EditField
        EmbeddingMatrixEditField        matlab.ui.control.EditField
        MatrixconcentrationEditField    matlab.ui.control.NumericEditField
        GrowthMediumEditField           matlab.ui.control.EditField
        StainEditField                  matlab.ui.control.EditField
        ExperimentIDEditField           matlab.ui.control.EditField
        OperatorEditField               matlab.ui.control.EditField
        ViewtheimagesincolorCheckBox    matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        SegmenterTab                    matlab.ui.container.Tab
        MaxColonyRadiuspixelsSpinner    matlab.ui.control.Spinner
        ColonyEdgeThresholdSlider       matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        ProcessDataButton               matlab.ui.control.Button
        ColonySegmenterConfigurationLabel  matlab.ui.control.Label
        LesscoloniesMorecoloniesLabel   matlab.ui.control.Label
        IncreasetoremovemoreofthetipboundaryLabel  matlab.ui.control.Label
        InfoLabel                       matlab.ui.control.Label
        ExamineeachexperimentplatemapaftersegmentingCheckBox  matlab.ui.control.CheckBox
        ApplycolonysegmentationmodelduringpreprocessingCheckBox  matlab.ui.control.CheckBox
        SelectparameterfileOptionalButton  matlab.ui.control.Button
        ColonyPixelMeasurementButton    matlab.ui.control.Button
        IftheprogramcrashesyoumayneedtoreducetheLabel  matlab.ui.control.Label
        UseparallelprocessingMorememorybutfasterCheckBox  matlab.ui.control.CheckBox
        SegmenterModelSpinnerLabel      matlab.ui.control.Label
        SegmenterModelSpinner           matlab.ui.control.Spinner
        SensitivitySliderLabel          matlab.ui.control.Label
        SensitivitySlider               matlab.ui.control.Slider
        TipMaskSpinnerLabel             matlab.ui.control.Label
        TipMaskSpinner                  matlab.ui.control.Spinner
        EstimatedColonyDiameterpixelsSpinnerLabel  matlab.ui.control.Label
        EstimatedColonyDiameterpixelsSpinner  matlab.ui.control.Spinner
        Modeltoapply59Spinner_2Label    matlab.ui.control.Label
        Modeltoapply59Spinner_2         matlab.ui.control.Spinner
        ProcessExistingDataLabel        matlab.ui.control.Label
        OverwritePriorProcessingCheckBox  matlab.ui.control.CheckBox
        MaxColonyRadiuspixelsSpinnerLabel  matlab.ui.control.Label
        ColonyEdgeThresholdSliderLabel  matlab.ui.control.Label
        SegmenterMessagesTextAreaLabel  matlab.ui.control.Label
        SegmenterMessagesTextArea       matlab.ui.control.TextArea
        AcquisitionTab                  matlab.ui.container.Tab
        ArduinoPortEditField            matlab.ui.control.EditField
        MotorstepsizemmEditField        matlab.ui.control.NumericEditField
        DistancetofirsttipmmEditField   matlab.ui.control.NumericEditField
        ISOEditField                    matlab.ui.control.NumericEditField
        fnumberEditField                matlab.ui.control.NumericEditField
        ShutterSpeedsEditField          matlab.ui.control.NumericEditField
        AcquirenewdataLabel             matlab.ui.control.Label
        CameramotorconfigurationLabel   matlab.ui.control.Label
        TipcolonysegmentationLabel      matlab.ui.control.Label
        ManualControlSwitch             matlab.ui.control.Switch
        RowCount1Label                  matlab.ui.control.Label
        SegmenttipswhileimagingCheckBox  matlab.ui.control.CheckBox
        AcquireButton                   matlab.ui.control.Button
        HomeMotorButton                 matlab.ui.control.Button
        RefreshArduinoandCameraButton   matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        ApplycolonysegmentationmodelduringimagingCheckBox  matlab.ui.control.CheckBox
        OptionalLoadprevioussegmentionparameterfileButton  matlab.ui.control.Button
        Modeltoapply59SpinnerLabel      matlab.ui.control.Label
        Modeltoapply59Spinner           matlab.ui.control.Spinner
        ResetRowCountButton             matlab.ui.control.Button
        ArduinoPortEditFieldLabel       matlab.ui.control.Label
        DistancetofirsttipmmEditFieldLabel  matlab.ui.control.Label
        MotorstepsizemmEditFieldLabel   matlab.ui.control.Label
        StoreasDefaultButton            matlab.ui.control.Button
        ISOEditFieldLabel               matlab.ui.control.Label
        fnumberEditFieldLabel           matlab.ui.control.Label
        ShutterSpeedsEditFieldLabel     matlab.ui.control.Label
        ManualControlSwitchLabel        matlab.ui.control.Label
        AcquisitionMessagesTextAreaLabel  matlab.ui.control.Label
        AcquisitionMessagesTextArea     matlab.ui.control.TextArea
        ExpDirEditFieldLabel            matlab.ui.control.Label
        PipettesRowEditFieldLabel       matlab.ui.control.Label
        ChannelLengthmmEditFieldLabel   matlab.ui.control.Label
        AssayVolumeuLEditFieldLabel     matlab.ui.control.Label
        ImagesFOVEditFieldLabel         matlab.ui.control.Label
        PipettesFOVEditFieldLabel       matlab.ui.control.Label
        PixelsizemmEditFieldLabel       matlab.ui.control.Label
        DilutionFactorEditFieldLabel    matlab.ui.control.Label
        ExperimentDateEditFieldLabel    matlab.ui.control.Label
        EmbeddingMatrixEditFieldLabel   matlab.ui.control.Label
        MatrixconcentrationEditFieldLabel  matlab.ui.control.Label
        GrowthMediumEditFieldLabel      matlab.ui.control.Label
        StainEditFieldLabel             matlab.ui.control.Label
        ExperimentIDEditFieldLabel      matlab.ui.control.Label
        OperatorEditFieldLabel          matlab.ui.control.Label
        SetImagingParametersLabel       matlab.ui.control.Label
        SetExperimentalConfigurationLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ThismayslightlyslowperformanceduetoincreasememoryLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% These are the variables used by different functions within the app.  
        %Arduino variables
        arduinoStat = 0; % Arduino Status
        port = "COM3"; % Arduino port.
        baud_rate = 115200; % Arduino baud rate.  Match baud rate in the hex files
        device = [];  %Serial port for arduino device;
 
        %Variables for keeping track of the number of images...
        rowCntr = 1; %Counting the rows
        fovCntr = 1; %Counting number of FOVs
        pathDir = [];  %Path directory
        segModelPath  = [];  %Segmentation file path (optional)

        %Variables for the stepper motor
        distPerStep = .020388; %Distance per step in mm.  Can be set by calibration.  See CalibrationButtonPushed function below.
        distBetweenTips = 9; %Distance between pipette tips in mm
        distToFirstImg = 6.9726; %Distance from home to first image in mm.  Home is determined by a limit switch.

        % Camera session
        C
        cameraStat = 0; % Camera status
        isonumber = 100;
        fnumber = 2.8;
        shutterspeed = 1/1000;

        % Processed image variables.  These are saved in a mat file
        collImgs = {};
        collList = {};
        collBtm = {};
        collAngle = {};
        collStart = {};
        fovIndx = {};
        CFUs = [];
        xPos = [];
        yPos = [];
        yPosmm = [];  
        
        %Some internal parameters to the segmenter configuration.
        maxCol = 20;  %Maximum number of colonies the segmenter algorithm looks for.                                                                             %%%%%%%%%%%%%%%%%%%%
        fudgeFactor = .5268;  %Size in mm beyond the tip of the pipette tip to the true point of the cone.  Found empirically. Equal to 80pixels on our camera   %%%%%%%%%%%%%%%%%%%%
        currZoomArray = [1,3,7,12,20];  %List of zooms magnification for the different segmenter models.                                                          %%%%%%%%%%%%%%%%%%%%
        
        processing = 0;  %Variable to store if the app is currently processing
    end
    
    methods (Access = private)
        function refreshCameraArduino(app)
            %% Connect to arduino
            try
                % Upload the hex code for controlling the arduino
                % ! "C:\Users\meyer\AppData\Local\Arduino15\packages\arduino\tools\avrdude\6.3.0-arduino17/bin/avrdude" "-CC:\Users\meyer\AppData\Local\Arduino15\packages\arduino\tools\avrdude\6.3.0-arduino17/etc/avrdude.conf" -v -V -patmega328p -carduino "-PCOM3" -b115200 -D "-Uflash:w:C:\Users\meyer\AppData\Local\Temp\arduino\sketches\E9ED96BD40BE23387324740CBBEF4358/2_matlab_move_motor_to_home.ino.hex:i"
                app.device = [];
                app.device = serialport(app.port,app.baud_rate);  %% Check if serial device can be accessed.
                app.arduinoStat = 1;  
            catch ME
                disp(ME)
                app.arduinoStat = 0;
                app.AcquisitionMessagesTextArea.Value = {'ERROR Cannot connect to Arduino...check connections then press Refresh Arduino'};
                app.RefreshArduinoandCameraButton.Visible = "on";
                app.ManualControlSwitch.Visible = "off";
                app.ManualControlSwitchLabel.Visible="off";
                app.HomeMotorButton.Visible = "off";
                app.CalibrateButton.Visible = "off";
                app.AcquireButton.Visible = "off";
                return
            end


            %% Connect to digicam controller
            app.C = CameraController;  %Using the matlab Digicam controller software which only works with windows.
            if ~isempty(app.C.lasterr)
                app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value', {'ERROR cannot connect to camera.  Make sure digicam controller is running! Then press Refresh Camera'}];
                app.AcquireButton.Visible = "off";
                app.cameraStat = 0;
                app.RefreshArduinoandCameraButton.Visible = "on";
                return
            else
                app.cameraStat = 1;
                app.AcquireButton.Visible = "on";
                app.C.session.filenametemplate = '[Camera Name]\[Date yyyy-MM-dd-hh-mm-ss]';
                app.C.session.useoriginalfilename = 0; %ignores "filenametemplate"
                app.C.session.downloadthumbonly = 0; %not working (v2.0.72.9)
                app.C.session.downloadonlyjpg = 0; %only used if "PC+CAM"
                app.C.session.deletefileaftertransfer = 1; %only has an effect if Transfer="Cam+PC" and affectively converts it to "PC only"
                app.C.session.asksavepath = 0; %dialogue pop-up for after capture
                app.C.session.allowoverwrite = 0; %overwrite if file exists
                app.C.session.lowercaseextension = 1; %use "*.jpg" instead of "*.JPG"
                 
                %% Camera settings  Set the camera mode to M and the lens focus to MF
                app.C.camera.isonumber = app.isonumber;
                app.C.camera.fnumber = app.fnumber;
                app.C.camera.shutterspeed = app.shutterspeed;
                app.C.camera.compressionsetting = 'Large Fine JPEG';
                app.C.camera.drive_mode = 'Single-Frame Shooting';
            end

            if app.cameraStat && app.arduinoStat
                app.RefreshArduinoandCameraButton.Visible = 'off';
                app.CalibrateButton.Visible="on";
                app.HomeMotorButton.Visible = "on";
                app.ManualControlSwitchLabel.Visible="on";
                app.ManualControlSwitch.Visible = "on";
                app.AcquisitionMessagesTextArea.Value = {'Camera and Arduino detected'};
            end

            app.port = app.ArduinoPortEditField.Value;
        end
        
        function freezeWhileProcessing(app,state)
            %Turn on some buttons when done processing
            app.PipettesRowEditField.Enable = state;
            app.AssayVolumeuLEditField.Enable = state;
            app.ChannelLengthmmEditField.Enable = state;
            app.ImagesFOVEditField.Enable = state;
            app.PipettesFOVEditField.Enable = state;
            app.RotateImageCheckBox.Enable = state;
            app.PixelsizemmEditField.Enable = state;
            app.DilutionFactorEditField.Enable = state;
            app.ExperimentDateEditField.Enable = state;
            app.EmbeddingMatrixEditField.Enable = state;
            app.MatrixconcentrationEditField.Enable = state;
            app.GrowthMediumEditField.Enable = state;
            app.StainEditField.Enable = state;
            app.ExperimentIDEditField.Enable = state;
            app.OperatorEditField.Enable = state;
            app.ViewtheimagesincolorCheckBox.Enable = state;
            app.SelectExperimentalDirectoryButton.Enable = state;
            app.ExpDirEditField.Enable = state;
            app.OverwritePriorProcessingCheckBox.Enable = state;
            app.ApplycolonysegmentationmodelduringpreprocessingCheckBox.Enable = state;
            app.Modeltoapply59Spinner_2.Enable = state;
            app.SelectparameterfileOptionalButton.Enable = state;
            app.UseparallelprocessingMorememorybutfasterCheckBox.Enable = state;
            app.StoreasDefaultConfigurationButton.Enable = state;  
            app.processing = ~state;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all')
            %Set the current experimental directory to the current working
            %directory by default.
            %% Set path dir
            
            if isdeployed 
                % User is running an executable in standalone mode. 
    			[~, result] = system('set PATH');
			    app.pathDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                disp(app.pathDir)
                %disp('Help')
            else
                % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [app.pathDir , ~ , ~] = fileparts(tmp_path);
                addpath(app.pathDir)
                addpath([app.pathDir filesep 'Functions'])
                %disp(app.pathDir)
                try
                    if exist('extract_pipet_function_parallel_with_manual_for_errors_fast','file')~=2
                        error('Incorrect path')
                    end
                catch ME  %If I failed to add the correct path then abort
                    app.AcquisitionMessagesTextArea.Value = {'Path is incorrect! This app wont work!'};
                    error('Incorrect Path')
                end
            end
            
            %app.segModelPath = [app.pathDir "segmentation_parameters.mat"];
            app.ExpDirEditField.Value = pwd;
            %Set the experiment date to today by default
            app.ExperimentDateEditField.Value = datestr(date,'yyyy-mm-dd');

            %Check that all the packages required are installed
            if ~isdeployed
                packages_req = {'Image Processing Toolbox','Signal Processing Toolbox','Parallel Computing Toolbox','Curve Fitting Toolbox'};
                success_vec = zeros(length(packages_req),1);
                pkgs = struct2cell(ver);
                if sum(contains(pkgs(1,:),packages_req))<length(packages_req)
                    msgbox(sprintf('%s\n','Missing the following toolboxes:',packages_req{contains(packages_req,pkgs(1,:))==0}))
                    error('App will not work as missing toolboxes');                    
                end
            end
            disp('Reading in tables')
            %Populate the default values
            Ta = readtable([app.pathDir filesep 'acquisition_default_parameters.csv'],"ReadVariableNames",false,"ReadRowNames",true);
            Ts = readtable([app.pathDir filesep 'imaging_default_parameters.csv'],"ReadVariableNames",false,"ReadRowNames",true);
            app.isonumber = Ta{'isonumber','Var1'};
            app.fnumber = Ta{'fnumber','Var1'};
            app.shutterspeed = Ta{'shutterspeed','Var1'};
            app.ISOEditField.Value = app.isonumber;
            app.fnumberEditField.Value = app.fnumber;
            app.ShutterSpeedsEditField.Value = app.shutterspeed;
            
            app.distPerStep = Ta{'distPerStep','Var1'};
            app.distBetweenTips = Ta{'distBetweenTips','Var1'};
            app.distToFirstImg = Ta{'distToFirstImg','Var1'};
            app.DistancetofirsttipmmEditField.Value = app.distToFirstImg;
            app.MotorstepsizemmEditField.Value = app.distPerStep;
            
            app.port = ['COM' num2str(Ta{'arduinoPort','Var1'})];
            %disp(app.port)
            app.ArduinoPortEditField.Value = app.port;
            
            app.PipettesRowEditField.Value = Ts{'Pipettes/Row','Var1'};
            app.AssayVolumeuLEditField.Value = Ts{'Assay Volume (uL)','Var1'};
            app.ChannelLengthmmEditField.Value = Ts{'Channel Length (mm)','Var1'};
            app.ImagesFOVEditField.Value = Ts{'Images/FOV','Var1'};
            app.PipettesFOVEditField.Value = Ts{'Pipettes/FOV','Var1'};
            app.RotateImageCheckBox.Value = Ts{'Rotate Image','Var1'};
            app.PixelsizemmEditField.Value = Ts{'Pixel size (mm)','Var1'};
            
            %Refresh the Camera and Arduino
            refreshCameraArduino(app);
        end

        % Button pushed function: SelectExperimentalDirectoryButton
        function SelectExperimentalDirectoryButtonPushed(app, event)
            %Before leaving the current directory, try and write out the
            %current experiment.
            toProcess = app.SegmenttipswhileimagingCheckBox.Value;
            activeDir = app.ExpDirEditField.Value;
            if toProcess
                dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                if ~isempty(dList)
                    dList = sort({dList.folder});
                    matDir = dList{length(dList)};
                    matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                    load(matFile);
                    xlsFile = dir(['**' filesep 'Well_treatments*']);
                    if isempty(xlsFile)
                        xlsFile = dir(['..' filesep '**' filesep 'Well_treatments*']);
                    end
                    % Try and write the results file...
                    if exist('CFUs','var')
                        %Create tables merging treatment annotation ('Well_treatments.xlsx') and
                        %data
                        count_mat = cellfun(@(x) numel(x),yPos);
                        count_mat(count_mat==0) = nan;
                        T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                        if ~isempty(xlsFile) %If condition annotation file exists try and merge tables
                            T2 = readtable([xlsFile(1).folder filesep xlsFile(1).name]);
                            %Try to merge accounting for well naming conventions
                            %('A01' and 'A1')
                            try
                                cond_T = join(T1,T2);
                            catch ME
                                for i = 1:height(T1)
                                    tmp = T1{i,'Well'};
                                    T1(i,'Well') = cell2table({[tmp{1}(1) sprintf('%02d',str2num(tmp{1}(2:length(tmp{1}))))]});
                                end
                                try
                                    cond_T = join(T1,T2);
                                catch
                                    T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                                    app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value', {['The platemap for: ' activeDir 'is incorrect!'],'Writing table without merging well treatments file!'}];
                                    cond_T = T1;
                                end
                            end
                        else %Just write the CFUs/well, the well, and the number of colonies counted.
                            app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value',{['No platemap detected: ' activeDir],'Writing table without merging well treatments file!'}];
                            cond_T = T1;                           
                        end
                        cond_T.exp_id = cellstr(repmat(exp_id, height(cond_T), 1));
                        writetable(cond_T,'CFUs_per_condition.csv')
                    end
                end
            end
            
            %Allow the user to select the data directory.  It will only
            %find data directories that have Well_treatments.xlsx and .jpg
            %files.
            tmp = uigetdir(app.ExpDirEditField.Value);
            figure(app.GVAUIFigure)
            if tmp~=0
                app.ExpDirEditField.Value = tmp;
            
                app.SegmenttipswhileimagingCheckBox.Visible = "on";
                app.ViewtheimagesincolorCheckBox.Visible = "on";
                app.ApplycolonysegmentationmodelduringimagingCheckBox.Visible = "on";
                app.Modeltoapply59Spinner.Visible = "on";
                app.Modeltoapply59SpinnerLabel.Visible = "on";
                app.OptionalLoadprevioussegmentionparameterfileButton.Visible = "on";
                
                app.PipettesFOVEditField.Enable = true;
                app.PipettesRowEditField.Enable = true;
                app.ImagesFOVEditField.Enable = true;
            
                app.rowCntr = 1;
                app.fovCntr = 1;
    
                app.RowCount1Label.Text = ['Row Count: ' num2str(app.rowCntr)];
    
                cd(app.ExpDirEditField.Value)
                %Populate app if Exp_config.csv file already exists!
                if exist([app.ExpDirEditField.Value filesep 'Exp_config.csv'],'file')==2
                    exp_config_T = readtable("Exp_config.csv");
                    app.ImagesFOVEditField.Value = exp_config_T{1,'nImgsPerFOV'};
                    app.PipettesFOVEditField.Value = exp_config_T{1,'pipettesPerFOV'};
                    app.PipettesRowEditField.Value = exp_config_T{1,'pipettesPerRow'};
                    app.ChannelLengthmmEditField.Value = exp_config_T{1,'channelL'};
                    app.AssayVolumeuLEditField.Value = exp_config_T{1,'assayVol'};
                    app.RotateImageCheckBox.Value = exp_config_T{1,'toRotate'};
                    app.DilutionFactorEditField.Value = exp_config_T{1,'dilutionFactor'};                     
                    app.ExperimentDateEditField.Value = string(exp_config_T{1,'expDate'});                     
                    app.EmbeddingMatrixEditField.Value = string(exp_config_T{1,'matrixType'});                    
                    app.MatrixconcentrationEditField.Value = exp_config_T{1,'matrixConc'};                
                    app.GrowthMediumEditField.Value = string(exp_config_T{1,'growthMedium'});                      
                    app.StainEditField.Value = string(exp_config_T{1,'stain'});                              
                    app.OperatorEditField.Value = string(exp_config_T{1,'operator'});                           
                    app.ExperimentIDEditField.Value = string(exp_config_T{1,'expID'});                       
                    app.PixelsizemmEditField.Value = exp_config_T{1,'pixSz'};
                    app.ViewtheimagesincolorCheckBox.Value = exp_config_T{1,'colorIm'};
                end
            end
        end

        % Button pushed function: StoreasDefaultConfigurationButton
        function StoreasDefaultConfigurationButtonPushed(app, event)
            %Populate the default values
            Ts = readtable([app.pathDir filesep 'imaging_default_parameters.csv'],"ReadVariableNames",false,"ReadRowNames",true);
            Ts{'Pipettes/Row','Var1'} = app.PipettesRowEditField.Value;
            Ts{'Assay Volume (uL)','Var1'} = app.AssayVolumeuLEditField.Value;
            Ts{'Channel Length (mm)','Var1'} = app.ChannelLengthmmEditField.Value;
            Ts{'Images/FOV','Var1'} = app.ImagesFOVEditField.Value;
            Ts{'Pipettes/FOV','Var1'} = app.PipettesFOVEditField.Value;
            Ts{'Rotate Image','Var1'} = app.RotateImageCheckBox.Value;
            Ts{'Pixel size (mm)','Var1'} = app.PixelsizemmEditField.Value;
            writetable(Ts,[app.pathDir filesep 'imaging_default_parameters.csv'],"WriteRowNames",true,"WriteVariableNames",false)
        end

        % Button pushed function: StoreasDefaultButton
        function StoreasDefaultButtonPushed(app, event)
            %Populate the default values
            Ta = readtable([app.pathDir filesep 'acquisition_default_parameters.csv'],"ReadVariableNames",false,"ReadRowNames",true);
            Ta{'isonumber','Var1'} = app.isonumber;
            Ta{'fnumber','Var1'} = app.fnumber;
            Ta{'shutterspeed','Var1'} = app.shutterspeed;
            Ta{'distPerStep','Var1'} = app.distPerStep;
            Ta{'distBetweenTips','Var1'} = app.distBetweenTips;
            Ta{'distToFirstImg','Var1'} = app.distToFirstImg;
            tmp = strsplit(app.port,'COM');
            Ta{'arduinoPort','Var1'} = str2double(tmp{2});
            writetable(Ta,[app.pathDir filesep 'acquisition_default_parameters.csv'],"WriteRowNames",true,"WriteVariableNames",false)
        end

        % Button pushed function: RefreshArduinoandCameraButton
        function RefreshArduinoandCameraButtonPushed(app, event)
           refreshCameraArduino(app); 
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            %If the arduino is not connected print fail message
            if app.arduinoStat == 0
                app.AcquisitionMessagesTextArea.Value = {"Arduino is not connected!"};
                return
            end
            
            %Turn off the buttons that could interfere while homing
            app.AcquireButton.Visible="off";
            app.CalibrateButton.Visible="off";
            app.HomeMotorButton.Visible = "off";
            app.RefreshArduinoandCameraButton.Visible = "off";
            app.ManualControlSwitch.Visible = "off";
            app.ManualControlSwitchLabel.Visible="off";

            app.AcquisitionMessagesTextArea.Value = 'Calibrate...';
            %First home the stage
            stepCnt = ceil(app.distToFirstImg/app.distPerStep);
            matlabHomeMotor(app.device,stepCnt)
            
            pipettesPerRow = app.PipettesRowEditField.Value;
            pipettesPerFOV = app.PipettesFOVEditField.Value;
           
            % Find the first side
            % Run while the manual control remains on
            stpCntr = ones(2,1);
            msgCell = {'first ','last '};
            for i = 1:2
                if i == 1
                    np = pipettesPerFOV;
                else
                    np = rem(pipettesPerRow,pipettesPerFOV);
                    if np==0  %last frame also should have pipettesPerFOV
                        np = pipettesPerFOV;
                    end
                end
                msg_text = ['Use button/switch to manually position stage and center the ' msgCell{i} num2str(np) ' tips in the row. Then press OK'];
                [stpCntr(i)] = matlabManualMotorControl(app.device,msg_text);             
            end
            stpCntr(1) = stepCnt+ stpCntr(1);

            numImgs = ceil(pipettesPerRow/pipettesPerFOV);
            distTraveled = (numImgs-1)*pipettesPerFOV*app.distBetweenTips;
            app.distPerStep = distTraveled/stpCntr(2);
            app.distToFirstImg = stpCntr(1)*app.distPerStep;
            app.DistancetofirsttipmmEditField.Value = app.distToFirstImg;
            app.MotorstepsizemmEditField.Value = app.distPerStep;
            disp(['Dist per step (mm):' num2str(app.distPerStep)]);
            disp(['dist to first image (mm):' num2str(app.distToFirstImg)]);

            %Home the stage again
            stepCnt = ceil(app.distToFirstImg/app.distPerStep);
            matlabHomeMotor(app.device,stepCnt)

            %Turn back on the buttons 
            app.AcquireButton.Visible="on";
            app.CalibrateButton.Visible="on";
            app.HomeMotorButton.Visible = "on";
            app.ManualControlSwitch.Visible = "on";
            app.ManualControlSwitchLabel.Visible="on";

            app.AcquisitionMessagesTextArea.Value = 'Calibrating...finished';
        end

        % Button pushed function: HomeMotorButton
        function HomeMotorButtonPushed(app, event)
            %If the arduino is not connected print fail message
            if app.arduinoStat == 0
                app.AcquisitionMessagesTextArea.Value = {"Arduino is not connected! Skipping homing"};
                app.RefreshArduinoandCameraButton.Visible = "on";
                return
            end
            
            %Turn off the buttons that could interfere while homing
            app.AcquireButton.Visible="off";
            app.CalibrateButton.Visible="off";
            app.HomeMotorButton.Visible = "off";
            app.ManualControlSwitch.Visible = "off";
            app.ManualControlSwitchLabel.Visible="off";

            app.AcquisitionMessagesTextArea.Value = {'Homing...'};
            %Move back forward to first imaging position
            stepCnt = ceil(app.distToFirstImg/app.distPerStep);
            %Home the motor using arduino
            matlabHomeMotor(app.device,stepCnt);         

            %Turn back on the buttons 
            if app.cameraStat == 1
                app.AcquireButton.Visible="on";
            end
            app.CalibrateButton.Visible="on";
            app.HomeMotorButton.Visible = "on";
            app.ManualControlSwitch.Visible = "on";
            app.ManualControlSwitchLabel.Visible="on";

            app.AcquisitionMessagesTextArea.Value = {'Homing...finished'};
        end

        % Button pushed function: 
        % OptionalLoadprevioussegmentionparameterfileButton
        function OptionalLoadprevioussegmentionparameterfileButtonPushed(app, event)
            [filename, filepath] = uigetfile('segmentation_parameters.mat','Select Segmentation File');
            if ischar(filename)
                app.segModelPath = [filepath filename];
                app.OptionalLoadprevioussegmentionparameterfileButton.Text = app.segModelPath;
            else
                app.OptionalselectsegmentparameterfileButton.Text = sprintf('(Optional) Load previous \n segmention parameter file');
            end
        end

        % Button pushed function: AcquireButton
        function AcquireButtonPushed(app, event)
            %If the arduino is not connected print fail message
            if app.arduinoStat == 0
                app.AcquisitionMessagesTextArea.Value = {"Arduino is not connected!  Cannot Acquire."};
                app.RefreshArduinoandCameraButton.Visible = "on";
                return
            end

            % If the camera is not connected print fail message
            if app.cameraStat == 0
                app.AcquisitionMessagesTextArea.Value = {"Error connecting to camera using Digicam.  Cannot Acquire."};
                app.RefreshArduinoandCameraButton.Visible = "on";
                return
            end            

            %Turn off the buttons that could interfere while acquiring
            app.AcquireButton.Visible="off";
            app.CalibrateButton.Visible="off";
            app.HomeMotorButton.Visible = "off";
            app.RefreshArduinoandCameraButton.Visible = "off";
            app.ManualControlSwitch.Visible = "off";
            app.ManualControlSwitchLabel.Visible="off";

            % Don't let the user change their mind on whether to process,
            % view in color, or segment while running the same experiment.
            % These buttons only turn back on after a new directory is
            % selected.
            app.SegmenttipswhileimagingCheckBox.Visible = "off";
            app.ViewtheimagesincolorCheckBox.Visible = "off";
            app.ApplycolonysegmentationmodelduringimagingCheckBox.Visible = "off";
            app.Modeltoapply59Spinner.Visible = "off";
            app.OptionalLoadprevioussegmentionparameterfileButton.Visible = "off";
            app.Modeltoapply59SpinnerLabel.Visible = "off";
            
            %Do not let user change the number of pipette tips wile imaging
            %This is turned back on once a new directory is selected.
            app.PipettesFOVEditField.Enable = false;
            app.PipettesRowEditField.Enable = false;
            app.ImagesFOVEditField.Enable = false;
            
            %% First home the stage
            %Move back forward to first imaging position
            stepCnt = ceil(app.distToFirstImg/app.distPerStep);
            %Home the motor using arduino
            matlabHomeMotor(app.device,stepCnt);        
            
            %% Some internal parameters to the segmenter configuration.
            maxCol = app.maxCol;  %Maximum number of colonies the segmenter algorithm looks for.                                                                             %%%%%%%%%%%%%%%%%%%%
            fudgeFactor = app.fudgeFactor;  %Size in mm beyond the tip of the pipette tip to the true point of the cone.  Found empirically. Equal to 80pixels on our camera   %%%%%%%%%%%%%%%%%%%%
            currZoomArray = app.currZoomArray;  %List of zooms magnification for the different segmenter models.                                                          %%%%%%%%%%%%%%%%%%%%
            % Turn the warnings off
            warning('off','all');

            %% Move into active directory and set camera session folder 
            activeDir = app.ExpDirEditField.Value;
            app.C.session.folder = activeDir;  %Set the camera session directory to active directory
            cd(activeDir)
            camName = app.C.CheckCamera;
            toProcess = app.SegmenttipswhileimagingCheckBox.Value;
            toSegment = app.ApplycolonysegmentationmodelduringimagingCheckBox.Value;
            % Let the user know you are working on it...
            if toProcess
                app.AcquisitionMessagesTextArea.Value = {'Acquiring and Processing'};
            else
                app.AcquisitionMessagesTextArea.Value = {'Acquiring'};
            end                

            app.RowCount1Label.Text = ['Row Count: ' num2str(app.rowCntr)];

            %% Get imaging parameters from the widgets
            nImgsPerFOV    = app.ImagesFOVEditField.Value;                          % Number of camera images per field of view (FOV)
            wellsPerFOV    = app.PipettesFOVEditField.Value;                        % Number of pipet tips per FOV (usually 2 or 3)
            wellsPerRow    = app.PipettesRowEditField.Value;                        % Number of wells in a given row (usually 8 or 12)
            channelL       = app.ChannelLengthmmEditField.Value;                    % Agarose cone length in pipet tip (mm)
            assayVol       = app.AssayVolumeuLEditField.Value;                      % Total amount of uL in pipet tip
            toRotate       = app.RotateImageCheckBox.Value;                  % Flip the y dimension?
            colorIm        = app.ViewtheimagesincolorCheckBox.Value;                % Should the images be displayed in color?

            %% Experimental configuration
            % Note this date is assigned to all folders in activeDirList cell array
            dilutionFactor = app.DilutionFactorEditField.Value;                     % Dilution factor (including into agarose)
            expDate        = app.ExperimentDateEditField.Value;                     % Experiment date
            matrixType    = app.EmbeddingMatrixEditField.Value;                    % Type of agarose used
            matrixConc    = app.MatrixconcentrationEditField.Value;                % Concentration of agarose used
            growthMedium   = app.GrowthMediumEditField.Value;                       % Growth medium
            stain          = app.StainEditField.Value;                              % Stain
            operator       = app.OperatorEditField.Value;                           % Who did the experiment
            screenID       = app.ExperimentIDEditField.Value;                       % Screen name
            pixSz          = app.PixelsizemmEditField.Value;                        %Pixel size of camera  Current: 0.00658616904;  Updated 2022.05.19 based on  HT_CFU_assay/2022-05-18-dropCFU_GVA_corr/20220518-pixel_size_calibration.jpg
    
            % Write experimental configuration file if it does not exist already.
            exp_config_T = cell2table({activeDir,nImgsPerFOV,wellsPerFOV,wellsPerRow,channelL,assayVol,toRotate,dilutionFactor,expDate,matrixType,matrixConc,growthMedium,stain,operator,screenID,colorIm,pixSz},'VariableNames',{'directory','nImgsPerFOV','pipettesPerFOV','pipettesPerRow','channelL','assayVol','toRotate','dilutionFactor','expDate','matrixType','matrixConc','growthMedium','stain','operator','expID','colorIm','pixSz'});
            writetable(exp_config_T,'Exp_config.csv')

            %% Create or load the segmentation file 
            if toSegment
                if ~isempty(app.segModelPath)
                    load(app.segModelPath,'segT')
                    save([activeDir filesep 'segmentation_parameters.mat'],'segT')
                elseif exist([activeDir filesep 'segmentation_parameters.mat'],'file')
                    load([activeDir filesep 'segmentation_parameters.mat'],'segT')
                else
                    load([app.pathDir filesep 'segmentation_parameters.mat'],'segT')
                    save([activeDir filesep 'segmentation_parameters.mat'],'segT')  %segT was loaded from segmentation_parameters.mat in the pathDir
                end
            end
                
            %% Setup the locations where everything will be saved
            if toProcess
                matDir     = [activeDir filesep camName filesep 'SavedMat_' datestr(now,29)];
                if exist(matDir,'dir')~=7
                    mkdir(matDir)
                end
                if exist([matDir filesep 'Combined_extracted_wells.mat'],'file')~=2
                    save([matDir filesep 'Combined_extracted_wells.mat'],'activeDir','-v7.3','-nocompression')
                end
            end

            %% Capture the images in a loop total numFOV times
            pipettesPerRow = app.PipettesRowEditField.Value;
            pipettesPerFOV = app.PipettesFOVEditField.Value;
            numFOV = ceil(pipettesPerRow/pipettesPerFOV); % Number of fovs to image

            wIndx = 0;
            for nn = 1:numFOV
                %% Capture image saving the file name
                fList = cell(nImgsPerFOV,1);
                for kk = 1:nImgsPerFOV
                    app.C.Capture;  %Capture image
                    %Wait until file exists before proceeding.
                    while 1
                        fList{kk} = [camName filesep app.C.lastfile]; %get last downloaded filenames  %% DO I NEED THE FOLDER AS WELL?
                        [status,values] = fileattrib(fList{kk});
                        if status==1 && values.UserRead==1
                            break;
                        end
                    end
                end
                %% Optionally preprocess the pipette tips ~2 sec per FOV
                if toProcess
                    %save('process.mat','fList','nImgsPerFOV','wellsPerRow','toRotate','colorIm','pixSz')
                    disp('segmenting')
                    [tmp_collImgs,tmp_tipAngle,tmp_tipBtm,tmp_collStart] = extract_pipet_function_while_aquiring(fList,nImgsPerFOV,wellsPerRow,toRotate,colorIm,pixSz);
                    currRowChar = char(app.rowCntr + 64);
                    nWells = length(tmp_collImgs);
                    for ww = 1:nWells
                        wIndx = wIndx + 1;
                        app.collList{app.rowCntr,wIndx} = [currRowChar num2str(wIndx)];
                        if ww == 1
                            app.fovIndx{app.fovCntr} = [app.rowCntr,wIndx];
                        else
                            app.fovIndx{app.fovCntr} = [app.fovIndx{app.fovCntr};[app.rowCntr,wIndx]];
                        end
                        app.collImgs{app.rowCntr,wIndx} = tmp_collImgs{1,ww};
                        app.collStart{app.rowCntr,wIndx} = tmp_collStart{1,ww};
                        app.collAngle{app.rowCntr,wIndx} = tmp_tipAngle{1,ww};
                        app.collBtm{app.rowCntr,wIndx} = tmp_tipBtm{1,ww}; 
                        %% Optionally segment the colonies using a preselected model
                        if toSegment
                            modelNum = app.Modeltoapply59Spinner.Value;
                            currZoom = currZoomArray(modelNum-4);
                            im = permute(tmp_collImgs{1,ww},[2,1,3]);
                            rr = app.rowCntr;
                            app.CFUs(rr,wIndx) = 0;
                            app.xPos{rr,wIndx} = [];    
                            app.yPos{rr,wIndx} = [];
                            try
                                [a1,a2,cfu,~,~,~,~] = pipetteSegmenter_withMax_fast(im,activeDir,app.CFUs,[],...
                                                                                        currZoom,modelNum,[],[],channelL,assayVol,...
                                                                                        dilutionFactor,app.collList,rr,wIndx,ww,[],[],...
                                                                                        app.collStart,app.collBtm,app.collAngle,[],pixSz,[],maxCol,fudgeFactor);
                                app.xPos{rr,wIndx} = a2;
                                app.yPos{rr,wIndx} = a1;
                                app.yPosmm{rr,wIndx} = pixSz*sort(app.yPos{rr,wIndx} - app.collStart{rr,wIndx});
                                app.CFUs(rr,wIndx) = cfu(rr,wIndx);
                            end
                        end
                    end
                end
                %Increase the fov count
                app.fovCntr = app.fovCntr + 1;
                if nn<numFOV
                    motorDirection = 0; % Toward motor
                    stepCnt = app.distBetweenTips*pipettesPerFOV/app.distPerStep;
                    matlabMoveMotor(app.device,stepCnt,motorDirection)
                end
            end
            close all

            % Save the combined well images and identities
            if toProcess
                collImgs = app.collImgs;
                collList = app.collList;
                collAngle = app.collAngle;
                collStart = app.collStart;
                collBtm = app.collBtm;
                fovIndx = app.fovIndx;
                matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                save([matDir filesep 'Combined_extracted_wells.mat'],...
                     'collImgs','collList','collBtm','collAngle','collStart','activeDir','channelL','assayVol','collStart','fovIndx','-append','-v7.3','-nocompression');
                if toSegment
                    CFUs = app.CFUs;
                    xPos = app.xPos;
                    yPos = app.yPos;
                    yPosmm = app.yPosmm;
                    save(matFile,'CFUs','xPos','yPos','yPosmm','pixSz','dilutionFactor','-append','-v7.3','-nocompression')
                end
            end

            %Turn off the buttons that could interfere while homing
            app.AcquireButton.Visible="on";
            app.CalibrateButton.Visible="on";
            app.HomeMotorButton.Visible = "on";
            app.ManualControlSwitch.Visible = "on";
            app.ManualControlSwitchLabel.Visible="on";
            
            %Increase the row counter
            app.rowCntr = app.rowCntr + 1;
        end

        % Button pushed function: ResetRowCountButton
        function ResetRowCountButtonPushed(app, event)
            app.rowCntr = 1;
            app.fovCntr = 1;
            app.RowCount1Label.Text = ['Row Count: ' num2str(app.rowCntr)];

            toProcess = app.SegmenttipswhileimagingCheckBox.Value;
            activeDir = app.ExpDirEditField.Value;
            if toProcess
                dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                if ~isempty(dList)
                    dList = sort({dList.folder});
                    matDir = dList{length(dList)};
                    matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                    load(matFile);
                    xlsFile = dir(['**' filesep 'Well_treatments*']);
                    if isempty(xlsFile)
                        xlsFile = dir(['..' filesep '**' filesep 'Well_treatments*']);
                    end
                    % Try and write the results file...
                    if exist('CFUs','var')
                        %Create tables merging treatment annotation ('Well_treatments.xlsx') and
                        %data
                        count_mat = cellfun(@(x) numel(x),yPos);
                        count_mat(count_mat==0) = nan;
                        T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                        if ~isempty(xlsFile) %If condition annotation file exists try and merge tables
                            T2 = readtable([xlsFile(1).folder filesep xlsFile(1).name]);
                            %Try to merge accounting for well naming conventions
                            %('A01' and 'A1')
                            try
                                cond_T = join(T1,T2);
                            catch ME
                                for i = 1:height(T1)
                                    tmp = T1{i,'Well'};
                                    T1(i,'Well') = cell2table({[tmp{1}(1) sprintf('%02d',str2num(tmp{1}(2:length(tmp{1}))))]});
                                end
                                try
                                    cond_T = join(T1,T2);
                                catch
                                    T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                                    app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value',{['The platemap for: ' activeDir 'is incorrect!'],'Writing table without merging well treatments file!'}];
                                    cond_T = T1;
                                end
                            end
                        else %Just write the CFUs/well, the well, and the number of colonies counted.
                            app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value',{['No platemap detected: ' activeDir],'Writing table without merging well treatments file!'}];
                            cond_T = T1;                           
                        end
                        cond_T.exp_id = cellstr(repmat(exp_id, height(cond_T), 1));
                        writetable(cond_T,'CFUs_per_condition.csv')
                    end
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %Disable acquisition tab during image processing.
            if app.TabGroup.SelectedTab.Title == "Acquisition" && app.processing
                app.TabGroup.SelectedTab = app.SegmenterTab;
                return
            end
            if app.TabGroup.SelectedTab.Title == "Acquisition"
                disp("Refreshing Arduino")
                refreshCameraArduino(app);
            end
        end

        % Button pushed function: SelectparameterfileOptionalButton
        function SelectparameterfileOptionalButtonPushed(app, event)
            [filename, filepath] = uigetfile('segmentation_parameters.mat','Select Segmentation File');
            if ischar(filename)
                app.segModelPath = [filepath filename];
                app.SelectparameterfileOptionalButton.Text = app.segModelPath;
            else
                app.SelectparameterfileOptionalButton.Text = sprintf('Select parameter file \n (Optional)');
            end
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            %Some internal parameters to the segmenter configuration.
            maxCol = app.maxCol;  %Maximum number of colonies the segmenter algorithm looks for.                                                                             %%%%%%%%%%%%%%%%%%%%
            fudgeFactor = app.fudgeFactor;  %Size in mm beyond the tip of the pipette tip to the true point of the cone.  Found empirically. Equal to 80pixels on our camera   %%%%%%%%%%%%%%%%%%%%
            currZoomArray = app.currZoomArray;  %List of zooms magnification for the different segmenter models.                                                          %%%%%%%%%%%%%%%%%%%%
            % Turn the warnings off
            warning('off','all');
            
            %Turn off some buttons while processing is working
            freezeWhileProcessing(app,0)
            
            useParallel = app.UseparallelprocessingMorememorybutfasterCheckBox.Value;
            
            %% Find all the experimental directories that have a well treatments file and jpgs.
            %adl1 = dir([app.ExpDirEditField.Value filesep '**' filesep 'Well_treatments*.xlsx']);
            adl2 = dir([app.ExpDirEditField.Value filesep '**' filesep '*.jpg']);
            %activeDirList = intersect(unique({adl1.folder}),unique({adl2.folder}));
            activeDirList = unique({adl2.folder});
%             %If didn't find anything, try adding the image folder.  This works in cases when the Well_treatments.xlsx file is one directory above the jpg folder.
%             if isempty(activeDirList)
%                 subDir = [filesep 'Canon EOS RP (382029000199)'];
%                 adl1b = cell(length(adl1),1);
%                 for dd = 1:length(adl1)
%                     adl1b{dd} = [adl1(dd).folder subDir];
%                 end
%                 activeDirList = intersect(unique(adl1b),unique({adl2.folder}));
%             else
%                 subDir = '';
%             end
            
            %Should the code proceed?
            answer = questdlg(['Found ' num2str(length(activeDirList)) ' folders with jpgs.  Should we proceed?'], ...
                'Are you sure you want to proceed?', ...
                'Yes, Proceed','No Cancel','');
            if ~strcmp(answer,'Yes, Proceed')
                freezeWhileProcessing(app,1)
                return
            end          
            
            %% For each experimental directory
            wbfig = waitbar(0,'Preprocessing data....');  %Waitbar for how the processing is going.
            wbfig.Children.Title.Interpreter = 'none';
            for acdList = 1:length(activeDirList)
                %Change into experimental folder
                activeDir = activeDirList{acdList};
                activeDirShortName = strsplit(activeDir,filesep);
                activeDirShortName = strjoin(activeDirShortName(length(activeDirShortName)-1:length(activeDirShortName)),filesep);
                app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value', {['Preprocessing:' activeDirShortName]}];
                %Move into the active directory
                cd(activeDir)
                
                %Update the waitbar
                if isgraphics(wbfig) %If the waitbar is still around
                    waitbar((acdList-1)/length(activeDirList),wbfig,sprintf(['Preprocessing ' activeDirShortName]));
                else %Make a new waitbar
                    wbfig = waitbar((acdList-1)/length(activeDirList),sprintf(['Preprocessing ' activeDirShortName]));
                    wbfig.Children.Title.Interpreter = 'none';
                end
                if exist(['..' filesep 'Exp_config.csv'],'file')==2
                    exp_config_T = readtable(['..' filesep 'Exp_config.csv']);
                    app.ImagesFOVEditField.Value = exp_config_T{1,'nImgsPerFOV'};
                    app.PipettesFOVEditField.Value = exp_config_T{1,'pipettesPerFOV'};
                    app.PipettesRowEditField.Value = exp_config_T{1,'pipettesPerRow'};
                    app.ChannelLengthmmEditField.Value = exp_config_T{1,'channelL'};
                    app.AssayVolumeuLEditField.Value = exp_config_T{1,'assayVol'};
                    app.RotateImageCheckBox.Value = exp_config_T{1,'toRotate'};
                    app.DilutionFactorEditField.Value = exp_config_T{1,'dilutionFactor'};                     
                    app.ExperimentDateEditField.Value = string(exp_config_T{1,'expDate'});                     
                    app.EmbeddingMatrixEditField.Value = string(exp_config_T{1,'matrixType'});                    
                    app.MatrixconcentrationEditField.Value = exp_config_T{1,'matrixConc'};                
                    app.GrowthMediumEditField.Value = string(exp_config_T{1,'growthMedium'});                      
                    app.StainEditField.Value = string(exp_config_T{1,'stain'});                              
                    app.OperatorEditField.Value = string(exp_config_T{1,'operator'});                           
                    app.ExperimentIDEditField.Value = string(exp_config_T{1,'expID'});                       
                    app.PixelsizemmEditField.Value = exp_config_T{1,'pixSz'};
                    app.ViewtheimagesincolorCheckBox.Value = exp_config_T{1,'colorIm'};
                end
                                

                %% Get imaging parameters from the widgets
                nImgsPerFOV    = app.ImagesFOVEditField.Value;                          % Number of camera images per field of view (FOV)
                wellsPerFOV    = app.PipettesFOVEditField.Value;                        % Number of pipet tips per FOV (usually 2 or 3)
                wellsPerRow    = app.PipettesRowEditField.Value;                        % Number of wells in a given row (usually 8 or 12)
                channelL       = app.ChannelLengthmmEditField.Value;                    % Agarose cone length in pipet tip (mm)
                assayVol       = app.AssayVolumeuLEditField.Value;                      % Total amount of uL in pipet tip
                toRotate       = app.RotateImageCheckBox.Value;                         % Flip the y dimension?
                overWrite      = app.OverwritePriorProcessingCheckBox.Value;      % Should the previous image processing (if present) be overwritten? 1=yes,0=no
                colorIm        = app.ViewtheimagesincolorCheckBox.Value;                % Should the images be displayed in color?
                
                %% Experimental configuration
                % Note this date is assigned to all folders in activeDirList cell array
                dilutionFactor = app.DilutionFactorEditField.Value;                     % Dilution factor (including into agarose)
                expDate        = app.ExperimentDateEditField.Value;                     % Experiment date
                matrixType    = app.EmbeddingMatrixEditField.Value;                         % Type of agarose used
                matrixConc    = app.MatrixconcentrationEditField.Value;               % Concentration of agarose used
                growthMedium   = app.GrowthMediumEditField.Value;                       % Growth medium
                stain          = app.StainEditField.Value;                              % Stain
                operator       = app.OperatorEditField.Value;                           % Who did the experiment
                screenID       = app.ExperimentIDEditField.Value;                           % Screen name
                pixSz          = app.PixelsizemmEditField.Value;                        %Pixel size of camera  Current: 0.00658616904;  Updated 2022.05.19 based on  HT_CFU_assay/2022-05-18-dropCFU_GVA_corr/20220518-pixel_size_calibration.jpg

                % Write file.
                exp_id = activeDir;
                pathDir = app.pathDir;
                exp_config_T = cell2table({activeDir,nImgsPerFOV,wellsPerFOV,wellsPerRow,channelL,assayVol,toRotate,dilutionFactor,expDate,matrixType,matrixConc,growthMedium,stain,operator,screenID,colorIm,pixSz},'VariableNames',{'directory','nImgsPerFOV','pipettesPerFOV','pipettesPerRow','channelL','assayVol','toRotate','dilutionFactor','expDate','matrixType','matrixConc','growthMedium','stain','operator','expID','colorIm','pixSz'});
                if exist(['..' filesep 'Exp_config.csv'],'file')~=2
                    writetable(exp_config_T,['..' filesep 'Exp_config.csv'])
                end
                
                %% Create or load the segmentation file
                if ~isempty(app.segModelPath)
                    load(app.segModelPath,'segT')
                    %app.SegmenterMessagesTextArea.Value = sprintf([app.SegmenterMessagesTextArea.Value '... Loading selected segmentation configuration']);
                    save([activeDir filesep 'segmentation_parameters.mat'],'segT')
                elseif exist([activeDir filesep 'segmentation_parameters.mat'],'file')
                    load([activeDir filesep 'segmentation_parameters.mat'],'segT')
                    %app.SegmenterMessagesTextArea.Value = sprintf([app.SegmenterMessagesTextArea.Value '... Loading prior segmentation configuration']);
                else
                    load([pathDir filesep 'segmentation_parameters.mat'],'segT')
                    save([activeDir filesep 'segmentation_parameters.mat'],'segT')  %segT was loaded from segmentation_parameters.mat in the pathDir
                    %app.SegmenterMessagesTextArea.Value = sprintf([app.SegmenterMessagesTextArea.Value '... Generating default segmentation file']);
                end
                
                %% Extract the pipet tips from images.
                proceed = true;  %Variable used to skip subsequent steps if prior one's fail
                try
                    % Run the extraction through each directory only if previous data is
                    % not present or overWrite is 1
                    dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                    if isempty(dList) || overWrite
                        %Error checking
                        save('tmp_seg_input.mat','activeDir','nImgsPerFOV','wellsPerFOV','wellsPerRow','channelL','assayVol','pathDir','toRotate','pixSz','colorIm');
                        if useParallel
                            extract_pipet_function_parallel_with_manual_for_errors_fast(activeDir,nImgsPerFOV,wellsPerRow,channelL,assayVol,pathDir,toRotate,colorIm,pixSz,wellsPerFOV)                    
                        else
                            extract_pipet_function_with_manual_for_errors_fast(activeDir,nImgsPerFOV,wellsPerRow,channelL,assayVol,pathDir,toRotate,colorIm,pixSz,wellsPerFOV)                    
                        end                            
                    end
                    dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                    if isempty(dList)
                        error('Missing segmentation file')
                    else  %Make sure the activeDir is up to date
                        dList = sort({dList.folder});
                        matDir = dList{length(dList)};
                        matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                        disp(activeDir)
                        save(matFile,'activeDir','channelL','assayVol','-append','-v7.3','-nocompression')
                    end
                catch ME
                    app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value', {'Code for extracting pipette tips failed!',['Experiment:' activeDirShortName]}];
                    proceed = false;
                end
                
                %% Optionally segment with a predefined model
                if proceed && app.ApplycolonysegmentationmodelduringpreprocessingCheckBox.Value
                    app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{'...Running autosegmenter'}];
                    modelNum = app.Modeltoapply59Spinner_2.Value;
                    %Load segmentation model parameters
                    %Segmentation parameters
                    tipEdge = table2array(segT(segT.model==modelNum,'tipMask'));
                    minRadii = table2array(segT(segT.model==modelNum,'minRadii'));
                    colonyEdge = table2array(segT(segT.model==modelNum,'colonyEdge'));
                    colonySensitivity = table2array(segT(segT.model==modelNum,'colonySensitivity'));

                    %Get the most recent saved tip segementation images.
                    tmp = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                    dList = sort({tmp.folder});
                    matDir = dList{length(dList)};
                    matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                    if useParallel %Run segmentation in parallel
                        %Load the segmentation file
                        S = load(matFile);
                        assayVol = S.assayVol;
                        channelL = S.channelL;
                        collImgs = S.collImgs;
                        collList = S.collList;
                        collStart = S.collStart;
                        collBtm = S.collBtm;
                        collAngle = S.collAngle;
                        if isfield(S,'CFUs')
                            CFUs = S.CFUs;
                            pixSz = S.pixSz;
                            xPos = S.xPos;
                            yPos = S.yPos;
                            yPosmm = S.yPosmm;
                        else
                            yPos = cell(size(collImgs));
                            xPos = cell(size(collImgs));
                            yPosmm = cell(size(collImgs));
                            CFUs = zeros(size(collImgs));
                        end
                        [nRows,nWells] = size(collImgs);
                        wlls_to_segment = 1:(nRows*nWells);
                        %Place holder variables for populating in segmentation
                        %loop.
                        tmp_apY = cell(length(wlls_to_segment),1);
                        tmp_apX = cell(length(wlls_to_segment),1);
                        tmp_CFUs = zeros(length(wlls_to_segment),1);
                        currZoom = currZoomArray(modelNum-4);
                        %Segment in parallel.
                        parfor wll=1:length(wlls_to_segment)
                            [rr, nn] = ind2sub([nRows,nWells],wlls_to_segment(wll));
                            if colorIm
                                im = rgb2gray(collImgs{rr,nn})';
                            else
                                im = collImgs{rr,nn}';
                            end                                
                            try
                                [a1,a2,cfu,~,~,~,~] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,[],...
                                                                                        currZoom,modelNum,[],[],channelL,assayVol,...
                                                                                        dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                        collStart,collBtm,collAngle,[],pixSz,[],maxCol,fudgeFactor);
                                tmp_apY{wll} = a1;
                                tmp_apX{wll} = a2;
                                tmp_CFUs(wll) = cfu(rr,nn);
                            end
                        end
                    else %Run segmentation serially
                        load(matFile);
                        if ~exist('CFUs','var')
                            yPos = cell(size(collImgs));
                            xPos = cell(size(collImgs));
                            yPosmm = cell(size(collImgs));
                            CFUs = zeros(size(collImgs));
                        end
                        [nRows,nWells] = size(collImgs);
                        wlls_to_segment = 1:(nRows*nWells);
                        %Place holder variables for populating in segmentation
                        %loop.
                        tmp_apY = cell(length(wlls_to_segment),1);
                        tmp_apX = cell(length(wlls_to_segment),1);
                        tmp_CFUs = zeros(length(wlls_to_segment),1);
                        currZoom = currZoomArray(modelNum-4);
                        %Segment in parallel.
                        for wll=1:length(wlls_to_segment)
                            [rr, nn] = ind2sub([nRows,nWells],wlls_to_segment(wll));
                            if colorIm
                                im = rgb2gray(collImgs{rr,nn})';
                            else
                                im = collImgs{rr,nn}';
                            end  
                            try
                                [a1,a2,cfu,~,~,~,~] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,[],...
                                                                                        currZoom,modelNum,[],[],channelL,assayVol,...
                                                                                        dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                        collStart,collBtm,collAngle,[],pixSz,[],maxCol,fudgeFactor);
                                tmp_apY{wll} = a1;
                                tmp_apX{wll} = a2;
                                tmp_CFUs(wll) = cfu(rr,nn);
                            end
                        end
                    end
                    %Loop through wells and add the results of the
                    %segmenter to the correct cell arrays
                    cnt = 1;
                    for wll=1:length(wlls_to_segment)
                        [rr, nn] = ind2sub([nRows,nWells],wlls_to_segment(wll));
                        xPos{rr,nn} = tmp_apX{cnt};
                        yPos{rr,nn} = tmp_apY{cnt};
                        yPosmm{rr,nn} = pixSz*sort(yPos{rr,nn} - collStart{rr,nn});
                        CFUs(rr,nn) = tmp_CFUs(cnt);
                        cnt = cnt + 1;
                    end
                    save(matFile,'CFUs','xPos','yPos','yPosmm','pixSz','collStart','dilutionFactor','activeDir','-append','-v7.3','-nocompression')
                end
            end  %% END of preprocessing loop
            %Close the waitbar if there.
            if isgraphics(wbfig)
                close(wbfig)
            end

            %% Now loop through for the semi-automated segmentation algorithm
            % Use a while loop to allow for skipping around experimental
            % directories.
            acdList = 1;skipDir = 1;
            while acdList<=length(activeDirList)
                skipping_directories = 0;  %If skipping directory, don't examine plate map.
                activeDir = activeDirList{acdList};
                activeDirShortName = strsplit(activeDir,filesep);
                activeDirShortName = strjoin(activeDirShortName(length(activeDirShortName)-1),filesep);
                cd(activeDir)
                dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                if isempty(dList)  %If there is no preprocessed data skip with an error message
                    app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{['NO preprocessed data for: ' activeDirShortName '...Skipping!']}];
                else
                    app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{['Segmenter Processing:' activeDirShortName]}];
                    % Create figure for displaying pipette tip
                    screensize = get( groot, 'Screensize' );
                    fig = figure('color','w','Units','pixels','position',[1 screensize(4)/2 screensize(3) screensize(4)/1600*400],'Name',activeDirShortName,'NumberTitle','off');
                    ax = subplot(1,1,1);
                    %Accelerate figure rendering by disabling interactivity
                    %disableDefaultInteractivity(ax)

                    %Remove previous results
                    yPos = {};
                    xPos = {};
                    yPosmm = {};
                    CFUs = -1;
                    
                    % Get the most recent saved data and load it
                    dList = sort({dList.folder});
                    matDir = dList{length(dList)};
                    matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                    load(matFile);
                    % Load contains the following variables:
                    % 'collImgs','collList','collBtm','collAngle','collStart','activeDir','channelL','assayVol','collStart','fovIndx'
                    % (optionally) 'CFUs','xPos','yPos','yPosmm','pixSz','dilutionFactor'
                    if sum(CFUs(:)) ~= -1
                        %Recompute the CFUs in case the dilution factor was changed
                        for rr = 1:size(yPos,1)
                            for nn = 1:size(yPos,2)
                                if ~isempty(yPos{rr,nn})
                                    apX = xPos{rr,nn};
                                    apY = yPos{rr,nn};
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                end
                            end
                        end
                    else
                        yPos = cell(size(collImgs));
                        xPos = cell(size(collImgs));
                        yPosmm = cell(size(collImgs));
                        CFUs = zeros(size(collImgs)); 
                    end
                    [nRows,nWells] = size(collImgs); 
                    upperLOD = [];  %User defined upper limit of detection


                    %% Now loop through each well displaying the pipette tip and allowing the user to click.
                    wll = 1; currZoom =  1;  %Start with the zoom being the full well
                    app.ColonyPixelMeasurementButton.Visible = true;
                    while wll<=nRows*nWells
                        [rr, nn] = ind2sub([nRows,nWells],wll);
                        im = permute(collImgs{rr,nn},[2,1,3]);
                        tipAngle = collAngle{rr,nn};
                        startPix = collStart{rr,nn};
                        if ~isgraphics(ax)
                               fig = figure('color','w','Units','pixels','position',[1 screensize(4)/2 screensize(3) screensize(4)/1600*400]);
                               ax = subplot(1,1,1);
                               %Accelerate figure rendering by disabling interactivity
                               %disableDefaultInteractivity(ax)
                        else
                            cla(ax)
                        end
                        axes(ax)
                        hold on
                        imshow(im,[],'Parent',ax)
                        if colorIm
                            im = rgb2gray(im);
                        end
                        zoomPipetteTip(im,tipAngle,startPix,currZoom,ax,fig);
                        tip_line = xline(ax,collStart{rr,nn},'linewidth',2,'color','r');
                        if ~isempty(yPos{rr,nn})
                            clicked_points = scatter(ax,yPos{rr,nn},xPos{rr,nn},'r'); %Handle of current clicked points
                            apX = xPos{rr,nn};
                            apY = yPos{rr,nn};
                            CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                            title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Est. CFU=' num2str(CFUs(rr,nn),'%.2e')]);
                        else
                            apX = [];
                            apY = [];
                            clicked_points = [];
                            title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows)]);
                        end
                        bound_mask   = [];  %Holds the segmentation boundary plot
                        segmented = [0 0];  %Was the well segmented
                        zoom off;
                        truesize(fig)

                        bol = 1;
                        while bol
                            axes(ax)
                            try
                                [y,x,b] = ginput(1);
                            catch
                                b = 102;  %Assuming you meant to press f by closing the window
                            end
                            if isempty(b) %If button is empty set equal to Enter key
                                b = -1;
                            end
                            switch b
                                case 115 %s (Skip to a new well)
                                    res = inputdlg('Enter well. either as an index (numerical) or well name (e.g. A5)','Skip well',1,{num2str(wll)});
                                    if ~isempty(res)
                                        res = upper(strtrim(res{1}));
                                        while length(res)>3
                                            res = inputdlg('Value must be <4 characters long!','Skip well',1,{num2str(wll)});
                                            res = strtrim(res{1});
                                        end
                                        if isnan(str2double(res)) %Must be well name
                                            ab = "ABCDEFGHIJKLMNOPQRSTUVWXYZ";
                                            rr_new = strfind(ab,res(1));
                                            cl_new = round(str2double(res(2:length(res))));
                                            wll = sub2ind([nRows,nWells],rr_new,cl_new);
                                            bol = 0;
                                        else
                                            wll = round(str2double(res));
                                            bol = 0;
                                        end
                                    end
                                case 108 %l (Remove all points to the left of cursor)
                                    delete(clicked_points);
                                    apX = apX(apY>=y);
                                    apY = apY(apY>=y);
                                    oldCFU = CFUs(rr,nn);
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                    clicked_points = scatter(ax,apY,apX,'r');
                                    fldChg = (CFUs(rr,nn)-oldCFU)/oldCFU*100;
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Est. CFU=' num2str(CFUs(rr,nn),'%.2e'), '; Percent change from last point=' num2str(fldChg,'%.0f') '%']);
                                case 114 %r (Remove all points to the right of cursor)
                                    delete(clicked_points);
                                    apX = apX(apY<=y);
                                    apY = apY(apY<=y);
                                    oldCFU = CFUs(rr,nn);
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                    clicked_points = scatter(ax,apY,apX,'r');
                                    fldChg = (CFUs(rr,nn)-oldCFU)/oldCFU*100;
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Est. CFU=' num2str(CFUs(rr,nn),'%.2e'), '; Percent change from last point=' num2str(fldChg,'%.0f') '%']);
                                case 101 %e (pipet error)
                                    CFUs(rr,nn) = nan;
                                    apY = [];
                                    apX = [];
                                    wll = wll+1;  %Move to the next well
                                    bol = 0;
                                case 102 %f (finished counting all wells)
                                    wll = nRows*nWells + 1;
                                    bol = 0;
                                case 122 %z  (rezoom)
                                    title(ax,'Zoom in or out using scroll-wheel, then press enter')
                                    h = zoom;h.Motion = 'horizontal';h.Enable = 'on';
                                    pause();
                                    zoom off;
                                case 1  %click (add point)
                                    delete(clicked_points);
                                    apX = [apX;x];
                                    apY = [apY;y];
                                    oldCFU = CFUs(rr,nn);
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                    clicked_points = scatter(ax,apY,apX,'r');
                                    fldChg = (CFUs(rr,nn)-oldCFU)/oldCFU*100;
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Est. CFU=' num2str(CFUs(rr,nn),'%.2e'), '; Percent change from last point=' num2str(fldChg,'%.0f') '%']);
                                case {-1,3} %Enter (next frame) or right click
                                    wll = wll+1;
                                    bol = 0;
                                case 112 %p  (previous frame)
                                    if wll>1
                                        wll = wll-1;
                                        bol = 0;
                                    else
                                        app.SegmenterMessagesTextArea.Value = sprintf([app.SegmenterMessagesTextArea.Value '\n' 'Currently on first frame!']);
                                    end
                                case 8 %backspace (remove previous point)
                                    delete(clicked_points);
                                    apX = apX(1:length(apX)-1);
                                    apY = apY(1:length(apY)-1);
                                    oldCFU = CFUs(rr,nn);
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                    clicked_points = scatter(ax,apY,apX,'r');
                                    fldChg = (CFUs(rr,nn)-oldCFU)/oldCFU*100;
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Est. CFU=' num2str(CFUs(rr,nn),'%.2e'), '; Percent change from last point=' num2str(fldChg,'%.0f') '%']);
                                case 127 %delete (remove all points)
                                    delete(clicked_points);
                                    apX = [];
                                    apY = [];
                                    CFUs(rr,nn) = 0;
                                    clicked_points = scatter(ax,apY,apX,'r');
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.']);
                                case 116 %t (reassign end of tip)
                                    delete(tip_line)
                                    collStart{rr,nn} = y;
                                    tip_line = xline(ax,collStart{rr,nn},'linewidth',2,'color','r');
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Current CFU: ' num2str(CFUs(rr,nn),'%.2e')]);
                                case 120 %x remove point closest to clicked point
                                    delete(clicked_points);
                                    [~,pt_dist_indx] = min((apX-x).^2+(apY-y).^2);
                                    apX(pt_dist_indx) = [];
                                    apY(pt_dist_indx) = [];
                                    oldCFU = CFUs(rr,nn);
                                    CFUs = CFUcalculator(CFUs,apX,apY,rr,nn,assayVol,dilutionFactor,fudgeFactor,pixSz,channelL,collStart);
                                    clicked_points = scatter(ax,apY,apX,'r');
                                    fldChg = (CFUs(rr,nn)-oldCFU)/oldCFU*100;
                                    title(ax,[collList{rr,nn} ': ' num2str(wll) ' of ' num2str(nWells*nRows) ' wells.  Est. CFU=' num2str(CFUs(rr,nn),'%.2e'), '; Percent change from last point=' num2str(fldChg,'%.0f') '%']);
                                case 100 %d (move to a new plate directory)
                                    tmp_activeDirShortNames = cell(length(activeDirList)+1,1);
                                    for aa = 1:length(activeDirList)
                                        tmp = activeDirList{aa};
                                        tmp = strsplit(tmp,filesep);
                                        tmp_activeDirShortNames{aa} = strjoin(tmp(length(tmp)-2:length(tmp)),filesep);
                                    end
                                    tmp_activeDirShortNames{aa+1} = 'Finished';
                                    [skipDir,selectedDir] = listdlg('PromptString',{'Select experiment directory to switch to.'},'SelectionMode','single','ListString',tmp_activeDirShortNames,'ListSize',[500,200]);
                                    if selectedDir
                                        skipping_directories = 1;
                                        wll = nRows*nWells + 1;
                                        bol = 0;
                                    end
                                case 109 %m (set tip to maximum limit of detection. user defined).
                                    if isempty(upperLOD)
                                        res = inputdlg('Enter the upper limit of detection accounting for dilution (must be numeric)','Upper LOD',1,{'1e9'});
                                        if ~isempty(res)  %If didn't hit cancel
                                            while true
                                                %Check that it is a numeric
                                                upperLOD = str2double(cell2mat(res));  % May or may not be numeric.
                                                if isnan(upperLOD)  %If not a nemeric run again
                                                    res = inputdlg('MUST BE NUMERIC!','Upper LOD',1,{'1e9'});
                                                    if isempty(res)  %If hit cancel break
                                                        upperLOD = [];
                                                        break
                                                    end
                                                else
                                                    break
                                                end
                                            end
                                        end
                                    end
                                    %Set CFUs to upper limit of detection
                                    if ~isempty(upperLOD)
                                        CFUs(rr,nn) = upperLOD;
                                    end
                                    wll = wll+1;
                                    bol = 0;
                                case 110 %n Make a note of this pipette tip.
                                    %Check if previous note file exists
                                    if isfile("condition_notes.txt")
                                        note_str = fileread("condition_notes.txt");
                                    else
                                        note_str = '';
                                    end
                                    fID = fopen('condition_notes.txt','w');
                                    res = inputdlg(['Enter a note for: '  collList{rr,nn}],'Note');
                                    fprintf(fID,[note_str collList{rr,nn} ': ' res{:} '\n\n']);
                                    fclose(fID);
                                case 99 %c Open contrast tool
                                    h = imcontrast(ax);
                                    while isgraphics(h)
                                        pause(1);
                                    end

                                %% Zoom levels    
                                case 49 %1 zoom to entire well
                                    currZoom = currZoomArray(1);
                                    zoomPipetteTip(im,tipAngle,startPix,currZoom,ax,fig);
                                case 50 %2 zoom level 2
                                    currZoom = currZoomArray(2);
                                    zoomPipetteTip(im,tipAngle,startPix,currZoom,ax,fig);
                                case 51 %3 zoom level 3
                                    currZoom = currZoomArray(3);
                                    zoomPipetteTip(im,tipAngle,startPix,currZoom,ax,fig);
                                case 52 %4 max zoom
                                    currZoom = currZoomArray(5);
                                    zoomPipetteTip(im,tipAngle,startPix,currZoom,ax,fig);
                                %% Segment the well!
                                case 49+4 %5  model 5 segmentation
                                    currZoom = currZoomArray(1);
                                    modelNum = 5;
                                    try
                                        [apY,apX,CFUs,clicked_points,app,radiiOut,bound_mask] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,clicked_points,...
                                                                                                                                currZoom,modelNum,ax,fig,channelL,assayVol,...
                                                                                                                                dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                                                                collStart,collBtm,collAngle,app,pixSz,bound_mask,maxCol,fudgeFactor);
                                        
                                    catch
                                        disp(['   Could not segment...']);
                                    end
                                    segmented = [1 5];
                                    truesize(fig)
                                case 50+4 %6 model 6 segmentation
                                    currZoom = currZoomArray(2);
                                    modelNum = 6;
                                    try
                                        [apY,apX,CFUs,clicked_points,app,radiiOut,bound_mask] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,clicked_points,...
                                                                                                                                currZoom,modelNum,ax,fig,channelL,assayVol,...
                                                                                                                                dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                                                                collStart,collBtm,collAngle,app,pixSz,bound_mask,maxCol,fudgeFactor);
                                        
                                    catch
                                        disp(['   Could not segment...']);
                                    end
                                    segmented = [1 6];
                                    truesize(fig)
                                case 51+4 %7 model 7 segmentation
                                    currZoom = currZoomArray(3);
                                    modelNum = 7;
                                    try
                                        [apY,apX,CFUs,clicked_points,app,radiiOut,bound_mask] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,clicked_points,...
                                                                                                                                currZoom,modelNum,ax,fig,channelL,assayVol,...
                                                                                                                                dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                                                                collStart,collBtm,collAngle,app,pixSz,bound_mask,maxCol,fudgeFactor);
                                        
                                    catch
                                        disp(['   Could not segment...']);
                                    end
                                    segmented = [1 7];
                                    truesize(fig)
                                case 52+4 %8 model 8 segmentation
                                    currZoom = currZoomArray(4);
                                    modelNum = 8;
                                    try
                                        [apY,apX,CFUs,clicked_points,app,radiiOut,bound_mask] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,clicked_points,...
                                                                                                                                currZoom,modelNum,ax,fig,channelL,assayVol,...
                                                                                                                                dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                                                                collStart,collBtm,collAngle,app,pixSz,bound_mask,maxCol,fudgeFactor);
                                        
                                    catch
                                        disp(['   Could not segment...']);
                                    end
                                    segmented = [1 8];
                                    truesize(fig)
                                case 53+4 %9 model 9 segmentation
                                    currZoom = currZoomArray(5);
                                    modelNum = 9;
                                    try
                                        [apY,apX,CFUs,clicked_points,app,radiiOut,bound_mask] = pipetteSegmenter_withMax_fast(im,activeDir,CFUs,clicked_points,...
                                                                                                                                currZoom,modelNum,ax,fig,channelL,assayVol,...
                                                                                                                                dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                                                                collStart,collBtm,collAngle,app,pixSz,bound_mask,maxCol,fudgeFactor);
                                        
                                    catch
                                        disp(['   Could not segment...']);
                                    end
                                    segmented = [1 9];
                                    truesize(fig)
                                case 97 %a  Segment all with current model
                                    currWll = wll;
                                    modelNum = app.SegmenterModelSpinner.Value;
                                    fmsg = msgbox(['Segmenting all with model: ' num2str(modelNum) ' ...this may take some time.  This box will close when segmenting is finished']);
                                    %Load segmentation model parameters
                                    load([activeDir filesep 'segmentation_parameters.mat'],'segT')
                                    %save('segment_all_test.mat','modelNum','currWll','useParallel','matFile','nWells','nRows','channelL','assayVol','dilutionFactor','maxCol','fudgeFactor','currZoomArray')
                                    %Segmentation parameters
                                    tipEdge = table2array(segT(segT.model==modelNum,'tipMask'));
                                    minRadii = table2array(segT(segT.model==modelNum,'minRadii'));
                                    colonyEdge = table2array(segT(segT.model==modelNum,'colonyEdge'));
                                    colonySensitivity = table2array(segT(segT.model==modelNum,'colonySensitivity'));
                                    %Load the segmentation file
                                    if useParallel
                                        S = load(matFile);
                                        assayVol = S.assayVol;
                                        channelL = S.channelL;
                                        collImgs = S.collImgs;
                                        collList = S.collList;
                                        collStart = S.collStart;
                                        collBtm = S.collBtm;
                                        collAngle = S.collAngle;
                                        if isfield(S,'CFUs')
                                            CFUs = S.CFUs;
                                            pixSz = S.pixSz;
                                            xPos = S.xPos;
                                            yPos = S.yPos;
                                            yPosmm = S.yPosmm;
                                        else
                                            yPos = cell(size(collImgs));
                                            xPos = cell(size(collImgs));
                                            yPosmm = cell(size(collImgs));
                                            CFUs = zeros(size(collImgs));
                                        end
                                        wlls_to_segment = currWll:(nRows*nWells);
                                        %Temp variables to fill in parallel for
                                        %loop.
                                        tmp_apY = cell(length(wlls_to_segment),1);
                                        tmp_apX = cell(length(wlls_to_segment),1);
                                        tmp_CFUs = zeros(length(wlls_to_segment),1);
                                        currZoom = currZoomArray(modelNum-4);
                                        %Loop through in parallel segmenting
                                        parfor wll=1:length(wlls_to_segment)
                                            [rr_tmp, nn_tmp] = ind2sub([nRows,nWells],wlls_to_segment(wll));
                                            tmpimg = permute(collImgs{rr_tmp,nn_tmp},[2,1,3]);
                                            if colorIm
                                                tmpimg = rgb2gray(tmpimg);
                                            end                         
                                            try
                                                [a1,a2,cfu,~,~,~,~] = pipetteSegmenter_withMax_fast(tmpimg,activeDir,CFUs,[],...
                                                                                                        currZoom,modelNum,[],[],channelL,assayVol,...
                                                                                                        dilutionFactor,collList,rr_tmp,nn_tmp,wll,nWells,nRows,...
                                                                                                        collStart,collBtm,collAngle,[],pixSz,[],maxCol,fudgeFactor);
                                                tmp_apY{wll} = a1;
                                                tmp_apX{wll} = a2;
                                                tmp_CFUs(wll) = cfu(rr_tmp,nn_tmp);
                                            end
                                        end
                                    else
                                        load(matFile);
                                        if ~exist('CFUs','var')
                                            yPos = cell(size(collImgs));
                                            xPos = cell(size(collImgs));
                                            yPosmm = cell(size(collImgs));
                                            CFUs = zeros(size(collImgs));
                                        end
                                        wlls_to_segment = currWll:(nRows*nWells);
                                        %Temp variables to fill in parallel for
                                        %loop.
                                        tmp_apY = cell(length(wlls_to_segment),1);
                                        tmp_apX = cell(length(wlls_to_segment),1);
                                        tmp_CFUs = zeros(length(wlls_to_segment),1);
                                        currZoom = currZoomArray(modelNum-4);
                                        %Loop through in parallel segmenting
                                        for wll=1:length(wlls_to_segment)
                                            [rr_tmp, nn_tmp] = ind2sub([nRows,nWells],wlls_to_segment(wll));
                                            tmpimg = permute(collImgs{rr_tmp,nn_tmp},[2,1,3]);
                                            if colorIm
                                                tmpimg = rgb2gray(tmpimg);
                                            end  
                                            try
                                                [a1,a2,cfu,~,~,~,~] = pipetteSegmenter_withMax_fast(tmpimg,activeDir,CFUs,[],...
                                                                                                        currZoom,modelNum,[],[],channelL,assayVol,...
                                                                                                        dilutionFactor,collList,rr,nn,wll,nWells,nRows,...
                                                                                                        collStart,collBtm,collAngle,[],pixSz,[],maxCol,fudgeFactor);
                                                tmp_apY{wll} = a1;
                                                tmp_apX{wll} = a2;
                                                tmp_CFUs(wll) = cfu(rr,nn);
                                            end
                                        end
                                    end
                                    %Re assign the segmenter results.
                                    cnt = 1;
                                    for wll=1:length(wlls_to_segment)
                                        [rr, nn] = ind2sub([nRows,nWells],wlls_to_segment(wll));
                                        xPos{rr,nn} = tmp_apX{cnt};
                                        yPos{rr,nn} = tmp_apY{cnt};
                                        yPosmm{rr,nn} = pixSz*sort(yPos{rr,nn} - collStart{rr,nn});
                                        CFUs(rr,nn) = tmp_CFUs(cnt);
                                        cnt = cnt + 1;
                                    end
                                    save(matFile,'CFUs','xPos','yPos','yPosmm','pixSz','collStart','activeDir','-append','-v7.3','-nocompression')
                                    wll = currWll;
                                    bol = 0;
                                    if exist('fmsg','var')
                                        close(fmsg)
                                    end
                                otherwise
                                    app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value' {[char(b) ' is not an option']}];
                            end
                        end
                        xPos{rr,nn} = apX;
                        yPos{rr,nn} = apY;
                        yPosmm{rr,nn} = pixSz*sort(yPos{rr,nn} - collStart{rr,nn});
                        
                        if rr == nRows %Save at the end of every row.
                            try
                                save(matFile,'CFUs','xPos','yPos','yPosmm','pixSz','collStart','activeDir','dilutionFactor','-append','-v7.3','-nocompression')
                            catch
                                disp('I did not save the previous file - go back and try again!')
                            end
                        end
                        
                        %Save information on segmentation if segmented.
%                         if segmented(1)==1
%                             %Update app values
%                             fileDirectory = {activeDir};
%                             rowNum    = rr;
%                             colNum    = nn;
%                             wellNm    = {collList{rr,nn}};
%                             modelNum  = app.Modeltoapply59Spinner_2.Value;
%                             maxRadii  = app.MaxColonyRadiuspixelsSpinner.Value;
%                             minRadii  = app.EstimatedColonyDiameterpixelsSpinner.Value;
%                             tipMask   = app.TipMaskSpinner.Value;
%                             colonyEdge = app.ColonyEdgeThresholdSlider.Value;
%                             colonySensitivity = app.SensitivitySlider.Value;
%                             CFUcnt          = CFUs(rr,nn);
%                             mst = table(fileDirectory,rowNum,colNum,wellNm,modelNum,maxRadii,minRadii,tipMask,colonyEdge,colonySensitivity,CFUcnt);
% 
%                             if exist([pathDir filesep 'model_selection_table.csv'],'file')
%                                 writetable(mst,[pathDir filesep 'model_selection_table.csv'],'WriteMode','Append','WriteVariableNames',false,'WriteRowNames',true)
%                             else
%                                 writetable(mst,[pathDir filesep 'model_selection_table.csv'],'WriteRowNames',true)
%                             end
%                         end

                        %Before finishing the experiment, set all the tips
                        %with zero CFUs to the lower limit of detection
                        %defined as 1/2 the value if 1 colony is in the tip
                        if (wll == nRows*nWells+1)
                            lod = 1000/assayVol*dilutionFactor/2;
                            CFUs(CFUs==0) = lod;
                            save(matFile,'CFUs','xPos','yPos','yPosmm','pixSz','collStart','activeDir','dilutionFactor','-append','-v7.3','-nocompression')
                        end
                        %Optionally examine the platemap
                        if (wll == nRows*nWells + 1) && (app.ExamineeachexperimentplatemapaftersegmentingCheckBox.Value) && (skipping_directories == 0)
                            [well2examine] = platemap_check(CFUs,collList,activeDir);
                            if ~isempty(well2examine)
                                if isnan(str2double(well2examine)) %Must be well name
                                    ab = "ABCDEFGHIJKLMNOPQRSTUVWXYZ";
                                    rr_new = strfind(ab,well2examine(1));
                                    cl_new = round(str2double(well2examine(2:length(well2examine))));
                                    wll = sub2ind([nRows,nWells],rr_new,cl_new);
                                    bol = 0;
                                else
                                    wll = round(str2double(well2examine));
                                    bol = 0;
                                end
                            end
                        end
                    end
                    %Turn off the colony pixel measurement button.
                    app.ColonyPixelMeasurementButton.Visible = false;
                    app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{'Finished writing table to experiment folder'}];
                    %If the pipet figure is still around close it.
                    try
                        close(fig)
                    end
                        
                    %Load the most recent data and create table merging the
                    %annotations from Well_treatments.xlsx...
                    load(matFile);
                    dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                    if ~isempty(dList)
                        dList = sort({dList.folder});
                        matDir = dList{length(dList)};
                        matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                        load(matFile);
                        xlsFile = dir(['**' filesep 'Well_treatments*']);
                        if isempty(xlsFile)
                            xlsFile = dir(['..' filesep '**' filesep 'Well_treatments*']);
                        end
                        % Try and write the results file...
                        if exist('CFUs','var')
                            %Create tables merging treatment annotation ('Well_treatments.xlsx') and
                            %data
                            count_mat = cellfun(@(x) numel(x),yPos);
                            count_mat(count_mat==0) = nan;
                            T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                            if ~isempty(xlsFile) %If condition annotation file exists try and merge tables
                                T2 = readtable([xlsFile(1).folder filesep xlsFile(1).name]);
                                %Try to merge accounting for well naming conventions
                                %('A01' and 'A1')
                                try
                                    cond_T = join(T1,T2);
                                catch ME
                                    for i = 1:height(T1)
                                        tmp = T1{i,'Well'};
                                        T1(i,'Well') = cell2table({[tmp{1}(1) sprintf('%02d',str2num(tmp{1}(2:length(tmp{1}))))]});
                                    end
                                    try
                                        cond_T = join(T1,T2);
                                    catch
                                        T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                                        app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{['The platemap for: ' activeDir 'is incorrect!'],['Writing table without merging well treatments file!']}];
                                        cond_T = T1;
                                    end
                                end
                            else %Just write the CFUs/well, the well, and the number of colonies counted.
                                app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{['No platemap detected: ' activeDir],'Writing table without merging well treatments file!'}];
                                cond_T = T1;                           
                            end
                            cond_T.exp_id = cellstr(repmat(exp_id, height(cond_T), 1));
                            writetable(cond_T,'CFUs_per_condition.csv')
                        end
                    end
                end
                if skipping_directories == 0
                    acdList = acdList + 1;
                else
                    acdList = skipDir;
                end
            end
            freezeWhileProcessing(app,1)
        end

        % Button pushed function: ColonyPixelMeasurementButton
        function ColonyPixelMeasurementButtonPushed(app, event)
            %disp('Here is a function to measure the colony diameter in pixels')
            h1 = findall(groot,'type','figure');
            figure(h1(1))
            ax = gca;
            f2 = figure();
            ax2 = copyobj(ax,f2);
            axes(ax2)
            title(ax2,'Zoom in to an average colony using scroll-wheel, then press enter')
            zoom on;
            pause();
            zoom off;
            title(ax2,'Click and drag mouse to make a circle around a colony')
            roi = drawcircle;

            app.SegmenterMessagesTextArea.Value = [app.SegmenterMessagesTextArea.Value',{['Colony diameter (pixels): ' num2str(round(roi.Radius*2))]}];
            app.EstimatedColonyDiameterpixelsSpinner.Value = round(roi.Radius*2);
            close(f2)
            figure(h1(1))
            robot = java.awt.Robot;
            robot.keyPress(java.awt.event.KeyEvent.(['VK_' num2str(1)]))
            robot.keyRelease(java.awt.event.KeyEvent.(['VK_' num2str(1)]))
        end

        % Value changed function: SegmenterModelSpinner
        function SegmenterModelSpinnerValueChanged(app, event)
            segmenterConfigurationUpdate_v2(app)            
        end

        % Value changed function: TipMaskSpinner
        function TipMaskSpinnerValueChanged(app, event)
            segmenterConfigurationUpdate_v2(app)            
        end

        % Value changed function: 
        % EstimatedColonyDiameterpixelsSpinner
        function EstimatedColonyDiameterpixelsSpinnerValueChanged(app, event)
            segmenterConfigurationUpdate_v2(app)
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            segmenterConfigurationUpdate_v2(app)            
        end

        % Value changed function: ArduinoPortEditField
        function ArduinoPortEditFieldValueChanged(app, event)
            app.port = app.ArduinoPortEditField.Value;
            refreshCameraArduino(app);
        end

        % Value changed function: MotorstepsizemmEditField
        function MotorstepsizemmEditFieldValueChanged(app, event)
            app.distPerStep = app.MotorstepsizemmEditField.Value;
            refreshCameraArduino(app);            
        end

        % Value changed function: DistancetofirsttipmmEditField
        function DistancetofirsttipmmEditFieldValueChanged(app, event)
            app.distToFirstImg = app.DistancetofirsttipmmEditField.Value;
            refreshCameraArduino(app);
        end

        % Value changed function: ISOEditField
        function ISOEditFieldValueChanged(app, event)
            app.isonumber = app.ISOEditField.Value;
            refreshCameraArduino(app);
        end

        % Value changed function: fnumberEditField
        function fnumberEditFieldValueChanged(app, event)
            app.fnumber = app.fnumberEditField.Value;
            refreshCameraArduino(app);
        end

        % Value changed function: ShutterSpeedsEditField
        function ShutterSpeedsEditFieldValueChanged(app, event)
            app.shutterspeed = app.ShutterSpeedsEditField.Value;
            refreshCameraArduino(app);
        end

        % Value changed function: ManualControlSwitch
        function ManualControlSwitchValueChanged(app, event)
            %If the arduino is not connected print fail message
            if app.arduinoStat == 0
                app.AcquisitionMessagesTextArea.Value = {"Arduino is not connected!"};
                app.RefreshArduinoandCameraButton.Visible = "on";
                return
            end
            %If manual control is turned on
            if app.ManualControlSwitch.Value
                %Turn off the buttons that could interfere
                app.AcquireButton.Visible="off";
                app.CalibrateButton.Visible="off";
                app.HomeMotorButton.Visible = "off";
                msg_text = "Press button/switch to move motor.  Press OK when finished.";
                %Call function for manually controlling motor
                matlabManualMotorControl(app.device,msg_text);
                app.ManualControlSwitch.Value = "Off";
                %Turn off the buttons that could interfere
                app.AcquireButton.Visible="on";
                app.CalibrateButton.Visible="on";
                app.HomeMotorButton.Visible = "on";
            end
        end

        % Value changed function: ExpDirEditField
        function ExpDirEditFieldValueChanged(app, event)
            %Before leaving the current directory, try and write out the
            %current experiment.
            toProcess = app.SegmenttipswhileimagingCheckBox.Value;
            activeDir = pwd;
            if toProcess
                dList = dir([activeDir filesep '*SavedMat*' filesep 'Combined_extracted_wells.mat']);
                if ~isempty(dList)
                    dList = sort({dList.folder});
                    matDir = dList{length(dList)};
                    matFile = [matDir filesep 'Combined_extracted_wells.mat'];
                    load(matFile);
                    xlsFile = dir([activeDir filesep 'Well_treatments*']);
                    % Try and write the results file...
                    if exist('CFUs','var') && ~isempty(xlsFile)
                        %Create tables merging treatment annotation ('Well_treatments.xlsx') and
                        %data
                        count_mat = cellfun(@(x) numel(x),yPos);
                        count_mat(count_mat==0) = nan;
                        T1 = table({collList{:}}',CFUs(:),count_mat(:),'VariableNames',{'Well','CFUs','Colonies Counted'});
                        T2 = readtable([activeDir filesep xlsFile(1).name]);
                        %Try to merge accounting for well naming conventions
                        %('A01' and 'A1')
                        try
                            cond_T = join(T1,T2);
                        catch ME
                            for i = 1:height(T1)
                                tmp = T1{i,'Well'};
                                T1(i,'Well') = cell2table({[tmp{1}(1) sprintf('%02d',str2num(tmp{1}(2:length(tmp{1}))))]});
                            end
                            try
                                cond_T = join(T1,T2);
                            catch
                                app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value',{['The platemap for: ' activeDir 'is incorrect!'],'Cannot write table! Fix and then reprocess'}];
                                cond_T = T1;
                            end
                        end
                        cond_T.exp_id = cellstr(repmat(exp_id, height(cond_T), 1));
                        writetable(cond_T,'CFUs_per_condition.csv')
                    elseif isempty(xlsFile) && exist('CFUs','var')
                            app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value',{['NO PLATEMAP in: ' activeDir],'Cannot write table! Fix and then reprocess'}];
                    end
                else
                    app.AcquisitionMessagesTextArea.Value = [app.AcquisitionMessagesTextArea.Value',{['No processed data in: ' activeDir],'Skipping table write'}];
                end
            end

            %Allow the user to select the data directory.  It will only
            %find data directories that have Well_treatments.xlsx and .jpg
            %files.
            if exist(app.ExpDirEditField.Value,'dir')            
                app.SegmenttipswhileimagingCheckBox.Visible = "on";
                app.ViewtheimagesincolorCheckBox.Visible = "on";
                app.ApplycolonysegmentationmodelduringimagingCheckBox.Visible = "on";
                app.Modeltoapply59Spinner.Visible = "on";
                app.Modeltoapply59SpinnerLabel.Visible = "on";
                app.OptionalLoadprevioussegmentionparameterfileButton.Visible = "on";
                
                app.PipettesFOVEditField.Enable = true;
                app.PipettesRowEditField.Enable = true;
                app.ImagesFOVEditField.Enable = true;
            
                app.rowCntr = 1;
                app.fovCntr = 1;
    
                app.RowCount1Label.Text = ['Row Count: ' num2str(app.rowCntr)];
    
                cd(app.ExpDirEditField.Value)
                %Populate app if Exp_config.csv file already exists!
                if exist('Exp_config.csv','file')==2
                    exp_config_T = readtable("Exp_config.csv");
                    app.ImagesFOVEditField.Value = exp_config_T{1,'nImgsPerFOV'};
                    app.PipettesFOVEditField.Value = exp_config_T{1,'pipettesPerFOV'};
                    app.PipettesRowEditField.Value = exp_config_T{1,'pipettesPerRow'};
                    app.ChannelLengthmmEditField.Value = exp_config_T{1,'channelL'};
                    app.AssayVolumeuLEditField.Value = exp_config_T{1,'assayVol'};
                    app.RotateImageCheckBox.Value = exp_config_T{1,'toRotate'};
                    app.DilutionFactorEditField.Value = exp_config_T{1,'dilutionFactor'};                     
                    app.ExperimentDateEditField.Value = string(exp_config_T{1,'expDate'});                     
                    app.EmbeddingMatrixEditField.Value = string(exp_config_T{1,'matrixType'});                    
                    app.MatrixconcentrationEditField.Value = exp_config_T{1,'matrixConc'};                
                    app.GrowthMediumEditField.Value = string(exp_config_T{1,'growthMedium'});                      
                    app.StainEditField.Value = string(exp_config_T{1,'stain'});                              
                    app.OperatorEditField.Value = string(exp_config_T{1,'operator'});                           
                    app.ExperimentIDEditField.Value = string(exp_config_T{1,'expID'});                       
                    app.PixelsizemmEditField.Value = exp_config_T{1,'pixSz'};
                    app.ViewtheimagesincolorCheckBox.Value = exp_config_T{1,'colorIm'};
                end
            else
                msgbox('Error: The input directory is not a valid folder.  Check for typos')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GVAUIFigure = uifigure('Visible', 'off');
            app.GVAUIFigure.Name = 'GVA';
            app.SelectExperimentalDirectoryButton = uibutton(app.GVAUIFigure, 'push');
            app.SelectExperimentalDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentalDirectoryButtonPushed, true);
            app.SelectExperimentalDirectoryButton.Tooltip = {'Select directory where the images will be saved.'};
            app.SelectExperimentalDirectoryButton.Text = 'Select Experimental Directory';
            app.ExpDirEditField = uieditfield(app.GVAUIFigure, 'text');
            app.ExpDirEditField.ValueChangedFcn = createCallbackFcn(app, @ExpDirEditFieldValueChanged, true);
            app.PipettesRowEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.PipettesRowEditField.Limits = [1 12];
            app.PipettesRowEditField.Tooltip = {'Number of pipettes per row.  Assumes the imaging was done row by row!'};
            app.PipettesRowEditField.Value = 12;
            app.AssayVolumeuLEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.AssayVolumeuLEditField.Limits = [1 1000];
            app.AssayVolumeuLEditField.Tooltip = {'Assay volume in uL'};
            app.AssayVolumeuLEditField.Value = 150;
            app.ChannelLengthmmEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.ChannelLengthmmEditField.Limits = [0 100];
            app.ChannelLengthmmEditField.Tooltip = {'Channel length in mm'};
            app.ChannelLengthmmEditField.Value = 36;
            app.ImagesFOVEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.ImagesFOVEditField.Limits = [0 10];
            app.ImagesFOVEditField.Tooltip = {'Number of images taken per field of view'};
            app.ImagesFOVEditField.Value = 1;
            app.PipettesFOVEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.PipettesFOVEditField.Limits = [1 12];
            app.PipettesFOVEditField.Tooltip = {'Number of pipettes in each field of view'};
            app.PipettesFOVEditField.Value = 3;
            app.RotateImageCheckBox = uicheckbox(app.GVAUIFigure);
            app.RotateImageCheckBox.Text = 'Rotate Image?';
            app.RotateImageCheckBox.Value = true;
            app.PixelsizemmEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.PixelsizemmEditField.Limits = [0 10];
            app.PixelsizemmEditField.Tooltip = {'Pixel size in millimeters.  To measure: take a picture of a ruler at the same focal distance and zoom in to count the pixels.'};
            app.PixelsizemmEditField.Value = 0.00658616904;
            app.StoreasDefaultConfigurationButton = uibutton(app.GVAUIFigure, 'push');
            app.StoreasDefaultConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @StoreasDefaultConfigurationButtonPushed, true);
            app.StoreasDefaultConfigurationButton.Text = 'Store as Default Configuration';
            app.DilutionFactorEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.DilutionFactorEditField.Limits = [0 100000000000];
            app.DilutionFactorEditField.ValueDisplayFormat = '%11.1e';
            app.DilutionFactorEditField.Tooltip = {'What is the final dilution factor of the sample in the embedding matrix.'};
            app.DilutionFactorEditField.Value = 100;
            app.ExperimentDateEditField = uieditfield(app.GVAUIFigure, 'text');
            app.ExperimentDateEditField.Value = '2022-07-19';
            app.EmbeddingMatrixEditField = uieditfield(app.GVAUIFigure, 'text');
            app.EmbeddingMatrixEditField.Value = 'Agarose RA';
            app.MatrixconcentrationEditField = uieditfield(app.GVAUIFigure, 'numeric');
            app.MatrixconcentrationEditField.Limits = [0 5];
            app.MatrixconcentrationEditField.Value = 0.5;
            app.GrowthMediumEditField = uieditfield(app.GVAUIFigure, 'text');
            app.GrowthMediumEditField.Value = 'LB';
            app.StainEditField = uieditfield(app.GVAUIFigure, 'text');
            app.StainEditField.Tooltip = {'If a stain was used to ID colonies.'};
            app.StainEditField.Value = 'TTC';
            app.ExperimentIDEditField = uieditfield(app.GVAUIFigure, 'text');
            app.ExperimentIDEditField.Tooltip = {'Useful for grouping different datasets.'};
            app.ExperimentIDEditField.Value = 'GVA_Test';
            app.OperatorEditField = uieditfield(app.GVAUIFigure, 'text');
            app.OperatorEditField.Value = 'YOU';
            app.ViewtheimagesincolorCheckBox = uicheckbox(app.GVAUIFigure);
            app.ViewtheimagesincolorCheckBox.Tooltip = {'This may slighlty slow performance due to increase memory'};
            app.ViewtheimagesincolorCheckBox.Text = 'View the images in color?';
            app.TabGroup = uitabgroup(app.GVAUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SegmenterTab = uitab(app.TabGroup);
            app.SegmenterTab.Title = 'Segmenter';
            app.MaxColonyRadiuspixelsSpinner = uispinner(app.SegmenterTab);
            app.MaxColonyRadiuspixelsSpinner.Editable = 'off';
            app.MaxColonyRadiuspixelsSpinner.Enable = 'off';
            app.MaxColonyRadiuspixelsSpinner.Visible = 'off';
            app.ColonyEdgeThresholdSlider = uislider(app.SegmenterTab);
            app.ColonyEdgeThresholdSlider.Enable = 'off';
            app.ColonyEdgeThresholdSlider.Visible = 'off';
            app.Label = uilabel(app.SegmenterTab);
            app.Label.Text = {'Hot Keys:'; 'left click               = Add point'; 'Enter/right click   = Next Frame'; 'p                          = Previous Frame'; 's                          = Skip to a specific well'; 'e                          = Pipette Error'; 'Bksp                    = Remove last point'; 'Del                       = Remove all points'; 'x                          = Remove point closest to cursor'; 't                           = Move tip end'; 'r                           = Remove colonies to the right of cursor'; 'l                           = Remove colonies to the left of cursor'; 'm                         = Set to upper limit of detection'; 'z                          = Manual Zoom'; 'f                           = Finish counting plate'; 'd                          = Navigate to new plate in experiment'; 'n                          = Make a note for tip'; 'c                          = Adjust image contrast'; '1,2,3,4                 = Zoom shortcuts'; '5,6,7,8,9              = Segmentation models'; 'a                          = Segment all remaining images for '; '                               this plate with the current model'};
            app.ProcessDataButton = uibutton(app.SegmenterTab, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data*';
            app.ColonySegmenterConfigurationLabel = uilabel(app.SegmenterTab);
            app.ColonySegmenterConfigurationLabel.Text = 'Colony Segmenter Configuration';
            app.LesscoloniesMorecoloniesLabel = uilabel(app.SegmenterTab);
            app.LesscoloniesMorecoloniesLabel.Text = '<--Less colonies  More colonies -->';
            app.IncreasetoremovemoreofthetipboundaryLabel = uilabel(app.SegmenterTab);
            app.IncreasetoremovemoreofthetipboundaryLabel.Text = 'Increase to remove more of the tip boundary';
            app.InfoLabel = uilabel(app.SegmenterTab);
            app.InfoLabel.Tooltip = {''};
            app.InfoLabel.Text = {'Due to the high diversity in colony size, this '; 'segmenter uses 5 different segmentation '; 'models selected by the user.  These model '; 'levels correspond to the hotkeys: 5,6,7,8,9'; 'Model 5 segments the entire pipette, while '; 'model 9 zooms into 1/10th the tip and only '; 'segments that portion.  The parameters for '; 'a particular model are saved as they are '; 'updated here, but only applied going forward. '; 'To reprocess prior images with updated '; 'parameters, navigate back to those images and '; 'reapply the model.'};
            app.ExamineeachexperimentplatemapaftersegmentingCheckBox = uicheckbox(app.SegmenterTab);
            app.ExamineeachexperimentplatemapaftersegmentingCheckBox.Text = 'Examine each experiment platemap after segmenting?';
            app.ExamineeachexperimentplatemapaftersegmentingCheckBox.Value = true;
            app.ApplycolonysegmentationmodelduringpreprocessingCheckBox = uicheckbox(app.SegmenterTab);
            app.ApplycolonysegmentationmodelduringpreprocessingCheckBox.Text = 'Apply colony segmentation model during preprocessing?';
            app.SelectparameterfileOptionalButton = uibutton(app.SegmenterTab, 'push');
            app.SelectparameterfileOptionalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectparameterfileOptionalButtonPushed, true);
            app.SelectparameterfileOptionalButton.Text = {'Select parameter file'; '(Optional)'; ''};
            app.ColonyPixelMeasurementButton = uibutton(app.SegmenterTab, 'push');
            app.ColonyPixelMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @ColonyPixelMeasurementButtonPushed, true);
            app.ColonyPixelMeasurementButton.Visible = 'off';
            app.ColonyPixelMeasurementButton.Text = 'Colony/Pixel Measurement';
            app.IftheprogramcrashesyoumayneedtoreducetheLabel = uilabel(app.SegmenterTab);
            app.IftheprogramcrashesyoumayneedtoreducetheLabel.Text = '*If matlab crashes while processing try reducing the number of parallel workers.';
            app.UseparallelprocessingMorememorybutfasterCheckBox = uicheckbox(app.SegmenterTab);
            app.UseparallelprocessingMorememorybutfasterCheckBox.Text = 'Use parallel processing?  More memory but faster.';
            app.UseparallelprocessingMorememorybutfasterCheckBox.Value = true;
            app.SegmenterModelSpinnerLabel = uilabel(app.SegmenterTab);
            app.SegmenterModelSpinnerLabel.Text = 'Segmenter Model';
            app.SegmenterModelSpinner = uispinner(app.SegmenterTab);
            app.SegmenterModelSpinner.Limits = [5 9];
            app.SegmenterModelSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmenterModelSpinnerValueChanged, true);
            app.SegmenterModelSpinner.Tooltip = {''};
            app.SegmenterModelSpinner.Value = 5;
            app.SensitivitySliderLabel = uilabel(app.SegmenterTab);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.SegmenterTab);
            app.SensitivitySlider.Limits = [0.8 1];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Tooltip = {'"Sensitivity" in the imfindcircles function.  Higher values find more colonies.'};
            app.SensitivitySlider.Value = 0.95;
            app.TipMaskSpinnerLabel = uilabel(app.SegmenterTab);
            app.TipMaskSpinnerLabel.Text = 'Tip Mask';
            app.TipMaskSpinner = uispinner(app.SegmenterTab);
            app.TipMaskSpinner.Limits = [1 100];
            app.TipMaskSpinner.ValueChangedFcn = createCallbackFcn(app, @TipMaskSpinnerValueChanged, true);
            app.TipMaskSpinner.Value = 25;
            app.EstimatedColonyDiameterpixelsSpinnerLabel = uilabel(app.SegmenterTab);
            app.EstimatedColonyDiameterpixelsSpinnerLabel.Tooltip = {'Dia'};
            app.EstimatedColonyDiameterpixelsSpinnerLabel.Text = {'Estimated Colony'; 'Diameter (pixels)'};
            app.EstimatedColonyDiameterpixelsSpinner = uispinner(app.SegmenterTab);
            app.EstimatedColonyDiameterpixelsSpinner.Step = 5;
            app.EstimatedColonyDiameterpixelsSpinner.Limits = [2 100];
            app.EstimatedColonyDiameterpixelsSpinner.RoundFractionalValues = 'on';
            app.EstimatedColonyDiameterpixelsSpinner.ValueChangedFcn = createCallbackFcn(app, @EstimatedColonyDiameterpixelsSpinnerValueChanged, true);
            app.EstimatedColonyDiameterpixelsSpinner.Tooltip = {'Minimum size of colony in image.  Increase to remove smaller colonies.  Cannot be bigger than Maximum colony size.'};
            app.EstimatedColonyDiameterpixelsSpinner.Value = 30;
            app.Modeltoapply59Spinner_2Label = uilabel(app.SegmenterTab);
            app.Modeltoapply59Spinner_2Label.Text = 'Model to apply (5-9)';
            app.Modeltoapply59Spinner_2 = uispinner(app.SegmenterTab);
            app.Modeltoapply59Spinner_2.Limits = [5 9];
            app.Modeltoapply59Spinner_2.Tooltip = {''};
            app.Modeltoapply59Spinner_2.Value = 5;
            app.ProcessExistingDataLabel = uilabel(app.SegmenterTab);
            app.ProcessExistingDataLabel.Text = 'Process Existing Data';
            app.OverwritePriorProcessingCheckBox = uicheckbox(app.SegmenterTab);
            app.OverwritePriorProcessingCheckBox.Text = 'Overwrite Prior Processing?';
            app.MaxColonyRadiuspixelsSpinnerLabel = uilabel(app.SegmenterTab);
            app.MaxColonyRadiuspixelsSpinnerLabel.Enable = 'off';
            app.MaxColonyRadiuspixelsSpinnerLabel.Visible = 'off';
            app.MaxColonyRadiuspixelsSpinnerLabel.Text = 'Max Colony Radius (pixels)';
            app.ColonyEdgeThresholdSliderLabel = uilabel(app.SegmenterTab);
            app.ColonyEdgeThresholdSliderLabel.Enable = 'off';
            app.ColonyEdgeThresholdSliderLabel.Visible = 'off';
            app.ColonyEdgeThresholdSliderLabel.Text = 'Colony Edge Threshold';
            app.SegmenterMessagesTextAreaLabel = uilabel(app.SegmenterTab);
            app.SegmenterMessagesTextAreaLabel.Text = 'Segmenter Messages';
            app.SegmenterMessagesTextArea = uitextarea(app.SegmenterTab);
            app.SegmenterMessagesTextArea.Editable = 'off';
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.ArduinoPortEditField = uieditfield(app.AcquisitionTab, 'text');
            app.ArduinoPortEditField.ValueChangedFcn = createCallbackFcn(app, @ArduinoPortEditFieldValueChanged, true);
            app.ArduinoPortEditField.Value = 'COM3';
            app.MotorstepsizemmEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.MotorstepsizemmEditField.ValueChangedFcn = createCallbackFcn(app, @MotorstepsizemmEditFieldValueChanged, true);
            app.MotorstepsizemmEditField.Value = 0.020388;
            app.DistancetofirsttipmmEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.DistancetofirsttipmmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancetofirsttipmmEditFieldValueChanged, true);
            app.DistancetofirsttipmmEditField.Value = 6.9726;
            app.ISOEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.ISOEditField.ValueChangedFcn = createCallbackFcn(app, @ISOEditFieldValueChanged, true);
            app.ISOEditField.Value = 100;
            app.fnumberEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.fnumberEditField.ValueChangedFcn = createCallbackFcn(app, @fnumberEditFieldValueChanged, true);
            app.fnumberEditField.Value = 2.8;
            app.ShutterSpeedsEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.ShutterSpeedsEditField.ValueChangedFcn = createCallbackFcn(app, @ShutterSpeedsEditFieldValueChanged, true);
            app.ShutterSpeedsEditField.Value = 0.001;
            app.AcquirenewdataLabel = uilabel(app.AcquisitionTab);
            app.AcquirenewdataLabel.Text = 'Acquire new data';
            app.CameramotorconfigurationLabel = uilabel(app.AcquisitionTab);
            app.CameramotorconfigurationLabel.Text = 'Camera/motor configuration';
            app.TipcolonysegmentationLabel = uilabel(app.AcquisitionTab);
            app.TipcolonysegmentationLabel.Text = 'Tip/colony segmentation';
            app.ManualControlSwitch = uiswitch(app.AcquisitionTab, 'slider');
            app.ManualControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualControlSwitchValueChanged, true);
            app.ManualControlSwitch.Tooltip = {'Manually control stage positioning using box buttons.'};
            app.RowCount1Label = uilabel(app.AcquisitionTab);
            app.RowCount1Label.Text = 'Row Count: 1';
            app.SegmenttipswhileimagingCheckBox = uicheckbox(app.AcquisitionTab);
            app.SegmenttipswhileimagingCheckBox.Tooltip = {'Should a segmentation of the pipette tips be run as the images are acquired?  This will slow down the acquisition time by ~2sec per FOV.'};
            app.SegmenttipswhileimagingCheckBox.Text = 'Segment tips while imaging?';
            app.AcquireButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireButtonPushed, true);
            app.AcquireButton.Text = 'Acquire';
            app.HomeMotorButton = uibutton(app.AcquisitionTab, 'push');
            app.HomeMotorButton.ButtonPushedFcn = createCallbackFcn(app, @HomeMotorButtonPushed, true);
            app.HomeMotorButton.Tooltip = {'Move the motor to the home position.'};
            app.HomeMotorButton.Text = 'Home Motor';
            app.RefreshArduinoandCameraButton = uibutton(app.AcquisitionTab, 'push');
            app.RefreshArduinoandCameraButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshArduinoandCameraButtonPushed, true);
            app.RefreshArduinoandCameraButton.Tooltip = {'If arduino or camera become disconnected, press this button after the physical connection has been reestablished. '};
            app.RefreshArduinoandCameraButton.Text = {'Refresh Arduino '; 'and Camera'};
            app.CalibrateButton = uibutton(app.AcquisitionTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Tooltip = {'Calibrate stage position movements to align the tips in the imaged fields of view.'};
            app.CalibrateButton.Text = 'Calibrate';
            app.ApplycolonysegmentationmodelduringimagingCheckBox = uicheckbox(app.AcquisitionTab);
            app.ApplycolonysegmentationmodelduringimagingCheckBox.Text = {'Apply colony segmentation model '; 'during imaging?'};
            app.OptionalLoadprevioussegmentionparameterfileButton = uibutton(app.AcquisitionTab, 'push');
            app.OptionalLoadprevioussegmentionparameterfileButton.ButtonPushedFcn = createCallbackFcn(app, @OptionalLoadprevioussegmentionparameterfileButtonPushed, true);
            app.OptionalLoadprevioussegmentionparameterfileButton.Tooltip = {'If a previously calibrated segmentation configuration should be used instead of the default.'};
            app.OptionalLoadprevioussegmentionparameterfileButton.Text = {'(Optional) Load previous '; 'segmention parameter file'};
            app.Modeltoapply59SpinnerLabel = uilabel(app.AcquisitionTab);
            app.Modeltoapply59SpinnerLabel.Text = 'Model to apply (5-9)';
            app.Modeltoapply59Spinner = uispinner(app.AcquisitionTab);
            app.Modeltoapply59Spinner.Limits = [5 9];
            app.Modeltoapply59Spinner.Tooltip = {''};
            app.Modeltoapply59Spinner.Value = 5;
            app.ResetRowCountButton = uibutton(app.AcquisitionTab, 'push');
            app.ResetRowCountButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRowCountButtonPushed, true);
            app.ResetRowCountButton.Tooltip = {'For a new experiment reset the row counter and select a new directory.'};
            app.ResetRowCountButton.Text = 'Reset Row Count';
            app.ArduinoPortEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ArduinoPortEditFieldLabel.Text = 'Arduino Port';
            app.DistancetofirsttipmmEditFieldLabel = uilabel(app.AcquisitionTab);
            app.DistancetofirsttipmmEditFieldLabel.Text = 'Distance to first tip (mm)';
            app.MotorstepsizemmEditFieldLabel = uilabel(app.AcquisitionTab);
            app.MotorstepsizemmEditFieldLabel.Text = 'Motor step size (mm)';
            app.StoreasDefaultButton = uibutton(app.AcquisitionTab, 'push');
            app.StoreasDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @StoreasDefaultButtonPushed, true);
            app.StoreasDefaultButton.Text = 'Store as Default';
            app.ISOEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ISOEditFieldLabel.Text = 'ISO';
            app.fnumberEditFieldLabel = uilabel(app.AcquisitionTab);
            app.fnumberEditFieldLabel.Text = 'f-number';
            app.ShutterSpeedsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.ShutterSpeedsEditFieldLabel.Text = 'Shutter Speed (s)';
            app.ManualControlSwitchLabel = uilabel(app.AcquisitionTab);
            app.ManualControlSwitchLabel.Text = 'Manual Control';
            app.AcquisitionMessagesTextAreaLabel = uilabel(app.AcquisitionTab);
            app.AcquisitionMessagesTextAreaLabel.Text = 'Acquisition Messages';
            app.AcquisitionMessagesTextArea = uitextarea(app.AcquisitionTab);
            app.AcquisitionMessagesTextArea.Editable = 'off';
            app.ExpDirEditFieldLabel = uilabel(app.GVAUIFigure);
            app.ExpDirEditFieldLabel.Text = 'Exp Dir';
            app.PipettesRowEditFieldLabel = uilabel(app.GVAUIFigure);
            app.PipettesRowEditFieldLabel.Text = '# Pipettes/Row';
            app.ChannelLengthmmEditFieldLabel = uilabel(app.GVAUIFigure);
            app.ChannelLengthmmEditFieldLabel.Tooltip = {'Length of the agarose cone inside pipette tip.'};
            app.ChannelLengthmmEditFieldLabel.Text = 'Channel Length (mm)';
            app.AssayVolumeuLEditFieldLabel = uilabel(app.GVAUIFigure);
            app.AssayVolumeuLEditFieldLabel.Text = 'Assay Volume (uL)';
            app.ImagesFOVEditFieldLabel = uilabel(app.GVAUIFigure);
            app.ImagesFOVEditFieldLabel.Text = '# Images/FOV';
            app.PipettesFOVEditFieldLabel = uilabel(app.GVAUIFigure);
            app.PipettesFOVEditFieldLabel.Text = '# Pipettes/FOV';
            app.PixelsizemmEditFieldLabel = uilabel(app.GVAUIFigure);
            app.PixelsizemmEditFieldLabel.Text = 'Pixel size (mm)';
            app.DilutionFactorEditFieldLabel = uilabel(app.GVAUIFigure);
            app.DilutionFactorEditFieldLabel.Text = 'Dilution Factor';
            app.ExperimentDateEditFieldLabel = uilabel(app.GVAUIFigure);
            app.ExperimentDateEditFieldLabel.Text = 'Experiment Date';
            app.EmbeddingMatrixEditFieldLabel = uilabel(app.GVAUIFigure);
            app.EmbeddingMatrixEditFieldLabel.Text = 'Embedding Matrix';
            app.MatrixconcentrationEditFieldLabel = uilabel(app.GVAUIFigure);
            app.MatrixconcentrationEditFieldLabel.Tooltip = {'% concentration'};
            app.MatrixconcentrationEditFieldLabel.Text = 'Matrix concentration';
            app.GrowthMediumEditFieldLabel = uilabel(app.GVAUIFigure);
            app.GrowthMediumEditFieldLabel.Text = 'Growth Medium';
            app.StainEditFieldLabel = uilabel(app.GVAUIFigure);
            app.StainEditFieldLabel.Text = 'Stain';
            app.ExperimentIDEditFieldLabel = uilabel(app.GVAUIFigure);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.OperatorEditFieldLabel = uilabel(app.GVAUIFigure);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.SetImagingParametersLabel = uilabel(app.GVAUIFigure);
            app.SetImagingParametersLabel.Text = 'Set Imaging Parameters';
            app.SetExperimentalConfigurationLabel = uilabel(app.GVAUIFigure);
            app.SetExperimentalConfigurationLabel.Text = 'Set Experimental Configuration';
            app.Label_2 = uilabel(app.GVAUIFigure);
            app.Label_2.Text = '*';
            app.ThismayslightlyslowperformanceduetoincreasememoryLabel = uilabel(app.GVAUIFigure);
            app.ThismayslightlyslowperformanceduetoincreasememoryLabel.Text = {'*This may slightly slow performance '; 'due to increase memory'};
            % Show the figure after all components are created
            app.GVAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GVA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GVAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GVAUIFigure)
        end
    end
end